classdef Intencity_Calc_13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FluvalCalcUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TimeofConcentrationTab          matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        AverageTcHoursEditField_2       matlab.ui.control.NumericEditField
        AverageTcHoursEditField_2Label_2  matlab.ui.control.Label
        ClearButton_3                   matlab.ui.control.Button
        CheckBox_25                     matlab.ui.control.CheckBox
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        AddmanuallyEditField            matlab.ui.control.NumericEditField
        AddmanuallyEditFieldLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TimeofConcentrationHoursLabel   matlab.ui.control.Label
        BocchiolasModelEditField        matlab.ui.control.NumericEditField
        BocchiolasModelEditFieldLabel   matlab.ui.control.Label
        FerrosModelEditField            matlab.ui.control.NumericEditField
        FerrosModelEditFieldLabel       matlab.ui.control.Label
        PilgrimandMcDermottsFormulaEditField  matlab.ui.control.NumericEditField
        PilgrimandMcDermottsFormulaLabel  matlab.ui.control.Label
        EspeysMethodEditField           matlab.ui.control.NumericEditField
        EspeysMethodEditFieldLabel      matlab.ui.control.Label
        HatkanirandSezenEditField       matlab.ui.control.NumericEditField
        HatkanirandSezenEditFieldLabel  matlab.ui.control.Label
        DoogesFormulaEditField          matlab.ui.control.NumericEditField
        DoogesFormulaEditFieldLabel     matlab.ui.control.Label
        ChowsModelEditField             matlab.ui.control.NumericEditField
        ChowsModelEditFieldLabel        matlab.ui.control.Label
        TmezsFormulaEditField           matlab.ui.control.NumericEditField
        TmezsFormulaEditFieldLabel      matlab.ui.control.Label
        SheridansFormulaEditField       matlab.ui.control.NumericEditField
        SheridansFormulaEditFieldLabel  matlab.ui.control.Label
        EatonsMethodEditField           matlab.ui.control.NumericEditField
        EatonsMethodEditFieldLabel      matlab.ui.control.Label
        PezzolisFormulaEditField        matlab.ui.control.NumericEditField
        PezzolisFormulaEditFieldLabel   matlab.ui.control.Label
        FattorelliandMarchisFormulaEditField  matlab.ui.control.NumericEditField
        FattorelliandMarchisFormulaEditFieldLabel  matlab.ui.control.Label
        PuglisiandZanframundosFormulaEditField  matlab.ui.control.NumericEditField
        PuglisiandZanframundosFormulaEditFieldLabel  matlab.ui.control.Label
        TournonsFormulaEditField        matlab.ui.control.NumericEditField
        TournonsFormulaEditFieldLabel   matlab.ui.control.Label
        PasinisFormulaEditField         matlab.ui.control.NumericEditField
        PasinisFormulaEditFieldLabel    matlab.ui.control.Label
        VenturasFormulaEditField        matlab.ui.control.NumericEditField
        VenturasFormulaEditFieldLabel   matlab.ui.control.Label
        TexasDepartmentoftransportationsformulaEditField  matlab.ui.control.NumericEditField
        TexasDepartmentoftransportationsformulaLabel  matlab.ui.control.Label
        NRCSSCSmethodEditField          matlab.ui.control.NumericEditField
        NRCSSCSmethodEditFieldLabel     matlab.ui.control.Label
        FederalAviationAgencysFormulaEditField  matlab.ui.control.NumericEditField
        FederalAviationAgencysFormulaEditFieldLabel  matlab.ui.control.Label
        CaliforniaCulvertPracticesMethodologyEditField  matlab.ui.control.NumericEditField
        CaliforniaCulvertPracticesMethodologyEditFieldLabel  matlab.ui.control.Label
        JohnstoneandCrosssmodelEditField  matlab.ui.control.NumericEditField
        JohnstoneandCrosssmodelEditFieldLabel  matlab.ui.control.Label
        KirpichsModelEditField          matlab.ui.control.NumericEditField
        KirpichsModelEditFieldLabel     matlab.ui.control.Label
        GiandottismodelEditField        matlab.ui.control.NumericEditField
        GiandottismodelEditFieldLabel   matlab.ui.control.Label
        BransbyWilliamssModelEditField  matlab.ui.control.NumericEditField
        BransbyWilliamssModelEditFieldLabel  matlab.ui.control.Label
        Akm2EditField                   matlab.ui.control.EditField
        Akm2EditFieldLabel              matlab.ui.control.Label
        SEditField                      matlab.ui.control.EditField
        SEditFieldLabel                 matlab.ui.control.Label
        LmEditField                     matlab.ui.control.EditField
        LmEditFieldLabel                matlab.ui.control.Label
        rEditField                      matlab.ui.control.EditField
        rEditFieldLabel                 matlab.ui.control.Label
        CNEditField                     matlab.ui.control.EditField
        CNEditFieldLabel                matlab.ui.control.Label
        SlopemeanEditField              matlab.ui.control.EditField
        SlopemeanLabel                  matlab.ui.control.Label
        CEditField                      matlab.ui.control.EditField
        CEditFieldLabel                 matlab.ui.control.Label
        DeltaHmEditField                matlab.ui.control.EditField
        DeltaHmEditFieldLabel           matlab.ui.control.Label
        HmEditField                     matlab.ui.control.EditField
        HmEditFieldLabel                matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        RainIntensityTab                matlab.ui.container.Tab
        CoordinatesLabel                matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        AverageTcHoursEditField         matlab.ui.control.NumericEditField
        AverageTcHoursEditField_2Label  matlab.ui.control.Label
        AverageIntensityIDWEditField    matlab.ui.control.NumericEditField
        AverageIntensityIDWEditFieldLabel  matlab.ui.control.Label
        FinalIntensitymmhrEditField     matlab.ui.control.NumericEditField
        FinalIntensitymmhrEditFieldLabel  matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        PlottingPostionPanel            matlab.ui.container.Panel
        plottingpositionCheckBox        matlab.ui.control.CheckBox
        plotting_postion3EditField      matlab.ui.control.NumericEditField
        plotting_postion2EditField      matlab.ui.control.NumericEditField
        plotting_postion1EditField      matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        LinearInterpolationEditField    matlab.ui.control.NumericEditField
        LinearInterpolationEditFieldLabel  matlab.ui.control.Label
        mmhrLabel_3                     matlab.ui.control.Label
        LogNormalCheckBox               matlab.ui.control.CheckBox
        LogPearsontypeIIICheckBox       matlab.ui.control.CheckBox
        GumbleCheckBox                  matlab.ui.control.CheckBox
        DIS3EditField                   matlab.ui.control.NumericEditField
        DIS2EditField                   matlab.ui.control.NumericEditField
        DIS1EditField                   matlab.ui.control.NumericEditField
        station3EditField               matlab.ui.control.EditField
        station3EditFieldLabel          matlab.ui.control.Label
        station2EditField               matlab.ui.control.EditField
        station2EditFieldLabel          matlab.ui.control.Label
        station1EditField               matlab.ui.control.EditField
        station1EditFieldLabel          matlab.ui.control.Label
        logNormal3EditField             matlab.ui.control.NumericEditField
        logNormal2EditField             matlab.ui.control.NumericEditField
        logNormal1EditField             matlab.ui.control.NumericEditField
        logPearson2EditField            matlab.ui.control.NumericEditField
        logPearson3EditField            matlab.ui.control.NumericEditField
        logPearson1EditField            matlab.ui.control.NumericEditField
        gumb2EditField                  matlab.ui.control.NumericEditField
        gumb3EditField                  matlab.ui.control.NumericEditField
        gumb1EditField                  matlab.ui.control.NumericEditField
        kmLabel_3                       matlab.ui.control.Label
        kmLabel_2                       matlab.ui.control.Label
        kmLabel                         matlab.ui.control.Label
        mmhrLabel_2                     matlab.ui.control.Label
        mmhrLabel                       matlab.ui.control.Label
        ReturnPeriodDropDown            matlab.ui.control.DropDown
        ReturnPeriodDropDownLabel       matlab.ui.control.Label
        DurationDropDown                matlab.ui.control.DropDown
        DurationDropDownLabel           matlab.ui.control.Label
        XEditField                      matlab.ui.control.EditField
        XEditField_2Label               matlab.ui.control.Label
        YEditField                      matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        AverageIntensityEditField       matlab.ui.control.NumericEditField
        AverageIntensityEditFieldLabel  matlab.ui.control.Label
        TheRainintensitymmhrindifferentdistributionsPanel  matlab.ui.container.Panel
        StationsPanel                   matlab.ui.container.Panel
        AdjustedCalculationPanel        matlab.ui.container.Panel
        CheckBox_28                     matlab.ui.control.CheckBox
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        IDFAverageTable                 matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        IDFGraphTab                     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        RunoffTab                       matlab.ui.container.Tab
        PlottingpostiongraphisbaseontheneareststationLabel  matlab.ui.control.Label
        FinalIntensitymmhrEditField_2   matlab.ui.control.NumericEditField
        FinalIntensitymmhrEditField_2Label  matlab.ui.control.Label
        ExportDatatoxlsxButton          matlab.ui.control.Button
        CEditField_2                    matlab.ui.control.EditField
        CEditField_2Label               matlab.ui.control.Label
        AdunamEditField_2               matlab.ui.control.EditField
        AdunamLabel                     matlab.ui.control.Label
        ClearButton_2                   matlab.ui.control.Button
        ComputeButton_2                 matlab.ui.control.Button
        Qpm3hrEditField                 matlab.ui.control.NumericEditField
        Qpm3hrEditFieldLabel            matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
         % Description
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: YEditField
        function YEditFieldValueChanging(app, event)
            
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
           
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
    % getting the list of station names
    [data, txt, ~] = xlsread('DistanceCalc.xlsx');

    % Get user-provided values coordinates in UTM
    y1 = str2double(app.YEditField.Value);
    x1 = str2double(app.XEditField.Value);

    % Calculate distances from user coordinates
    distances = sqrt((data(:,1) - y1).^2 + (data(:,2) - x1).^2)/1000;

    % Find the indices of the three shortest distances
    [~, indices] = sort(distances);

    % Display the three shortest distances in the numeric edit fields
    app.DIS1EditField.Value = distances(indices(1));
    app.DIS2EditField.Value = distances(indices(2));
    app.DIS3EditField.Value = distances(indices(3));

    % Display the corresponding place names from column F in the text edit fields
    app.station1EditField.Value = txt{indices(1)+2,6}; % +2 to skip the header row
    app.station2EditField.Value = txt{indices(2)+2,6};
    app.station3EditField.Value = txt{indices(3)+2,6};


     % Extract data from IDF folders
    for i = 1:3
        station_name = txt{indices(i)+2,6};
        idf_file_name = fullfile('Gumble_idf_anylsis', [station_name '_IDF_table.csv']);
        row_value = app.DurationDropDown.Value;
        col_value = app.ReturnPeriodDropDown.Value;

        % Check which drop-down menu was used and extract the data accordingly
        switch app.DurationDropDown.Value
            case '10 min'
                row_index = 2;
                string2 = '10 min';
            case '20 min'
                row_index = 3;
                string2 = '20 min';
            case '30 min'
                row_index = 4;
                string2 = '30 min';
            case '40 min'
                row_index = 5;
                string2 = '40 min';
            case '50 min'
                row_index = 6;
                string2 = '50 min';
            case '1 hr'
                row_index = 7;
                string2 = '60 min';
            case '1.5 hr'
                row_index = 8;
                string2 = '1.5 hr';
            case '2 hr'
                row_index = 9;
                string2 = '2 hr';
            case '2.5 hr'
                row_index = 10;
                string2 = '2.5 hr';
            case '3 hr'
                row_index = 11;
                string2 = '3 hr';
            case '3.5 hr'
                row_index = 12;
                string2 = '3.5 hr';
            case '4 hr'
                row_index = 13;
                string2 = '4 hr';
            case '4.5 hr'
                row_index = 14;
                string2 = '4.5 hr';
            case '5 hr'
                row_index = 15;
                string2 = '5 hr';
            case '6 hr'
                row_index = 16;
                string2 = '6 hr';
                          
        end

        switch app.ReturnPeriodDropDown.Value
            case '2 Years'
                col_index = 2;
            case '5 Years'
                col_index = 3;
            case '10 Years'
                col_index = 4;
            case '25 Years'
                col_index = 5;
            case '50 Years'
                col_index = 6;
            case '100 Years'
                col_index = 7;
            case '200 Years'
                col_index = 8;
            case '500 Years'
                col_index = 9;
            case '1000 Years'
                col_index = 10;  
        end

        % Read data from the IDF file folder for Gumbel.
        folder_path = 'Gumble_IDF';
        file_name = [station_name '_IDF_table.csv'];
        file_path = fullfile(folder_path, file_name);
        data_idf_gumble = csvread(file_path);
        value = data_idf_gumble(row_index, col_index);
        
         % Display the data in the numeric edit field and the name of the station in the text edit field
        if i == 1
            app.gumb1EditField.Value = value ;
        elseif i == 2
            app.gumb2EditField.Value = value ;
        elseif i == 3
            app.gumb3EditField.Value = value ;
        end
    end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% taking log_pearson data files. (not the same format as gumbel with matrix
% dimannsions)
    for i = 1:3
        station_name = txt{indices(i)+2,6};
        idf_file_name = fullfile('log_pearson_idf_anylsis', [station_name '_Pearson_type3_IDF.xls']);
        row_value = app.DurationDropDown.Value;
        col_value = app.ReturnPeriodDropDown.Value;

        % Check which drop-down menu was used and extract the data accordingly
        %בפירסון שיניתי את השורות ועמודות כי שורה אחד ועמודה אחד לא חלק
        %מהדאטה
        %אם גמבל ונורמל גם יהיו אקסלס צריך להעתיק מפירסון
        switch app.DurationDropDown.Value
            case '10 min'
                row_index = 1;
            case '20 min'
                row_index = 2;
            case '30 min'
                row_index = 3;
            case '40 min'
                row_index = 4;
            case '50 min'
                row_index = 5;
            case '1 hr'
                row_index = 6;
            case '1.5 hr'
                row_index = 7;
            case '2 hr'
                row_index = 8;
            case '2.5 hr'
                row_index = 9;
            case '3 hr'
                row_index = 10;
            case '3.5 hr'
                row_index = 11;
            case '4 hr'
                row_index = 12;
            case '4.5 hr'
                row_index = 13;
            case '5 hr'
                row_index = 14;
            case '6 hr'
                row_index = 15;
                          
        end

        switch app.ReturnPeriodDropDown.Value
            case '2 Years'
                col_index = 1;
            case '5 Years'
                col_index = 2;
            case '10 Years'
                col_index = 3;
            case '25 Years'
                col_index = 4;
            case '50 Years'
                col_index = 5;
            case '100 Years'
                col_index = 6;
            case '200 Years'
                col_index = 7;
            case '500 Years'
                col_index = 8;
            case '1000 Years'
                col_index = 9;    
        end

        % Read data from the IDF file for log pearson
        folder_path = 'idf_tabels_log_pearson';
        file_name = [station_name '_Pearson_type3_IDF.xls'];
        file_path = fullfile(folder_path, file_name);
        data_idf_pearson = readmatrix(file_path,'DataRange','B2:J16');
        value = data_idf_pearson(row_index, col_index);
        
         % Display the data in the numeric edit field and the name of the station in the text edit field
        if i == 1
            app.logPearson1EditField.Value = value ;
        elseif i == 2
            app.logPearson2EditField.Value = value ;
        elseif i == 3
            app.logPearson3EditField.Value = value ;
        end
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % getting Log normal tabels 

    for i = 1:3
        station_name = txt{indices(i)+2,6};
        idf_file_name = fullfile('Log_normal_anylsis', [station_name '_z_normal.csv']);
        row_value = app.DurationDropDown.Value;
        col_value = app.ReturnPeriodDropDown.Value;

        % Check which drop-down menu was used and extract the data accordingly
        switch app.DurationDropDown.Value
            case '10 min'
                row_index = 2;
            case '20 min'
                row_index = 3;
            case '30 min'
                row_index = 4;
            case '40 min'
                row_index = 5;
            case '50 min'
                row_index = 6;
            case '1 hr'
                row_index = 7;
            case '1.5 hr'
                row_index = 8;
            case '2 hr'
                row_index = 9;
            case '2.5 hr'
                row_index = 10;
            case '3 hr'
                row_index = 11;
            case '3.5 hr'
                row_index = 12;
            case '4 hr'
                row_index = 13;
            case '4.5 hr'
                row_index = 14;
            case '5 hr'
                row_index = 15;
            case '6 hr'
                row_index = 16;
                          
        end

        switch app.ReturnPeriodDropDown.Value
            case '2 Years'
                col_index = 2;
            case '5 Years'
                col_index = 3;
            case '10 Years'
                col_index = 4;
            case '25 Years'
                col_index = 5;
            case '50 Years'
                col_index = 6;
            case '100 Years'
                col_index = 7;
            case '200 Years'
                col_index = 8;
            case '500 Years'
                col_index = 9;
            case '1000 Years'
                col_index = 10;    
        end
        % Read data from the IDF file folder of log normal
        folder_path = 'log_normal_idf_tables';
        file_name = [station_name '_z_normal.csv'];
        file_path = fullfile(folder_path, file_name);
        data_idf_n = csvread(file_path);
        value = data_idf_n(row_index, col_index);
        
         % Display the data in the numeric edit field and the name of the station in the text edit field
        if i == 1
            app.logNormal1EditField.Value = value ;
        elseif i == 2
            app.logNormal2EditField.Value = value ;
        elseif i == 3
            app.logNormal3EditField.Value = value ;
        end
    end


    % using the frequency value as a number 
    switch app.ReturnPeriodDropDown.Value
        case '2 Years'
            T = 2;
        case '5 Years'
            T = 5;
        case '10 Years'
            T = 10;
        case '25 Years'
            T = 25;
        case '50 Years'
            T = 50;
        case '100 Years'
            T = 100;
        case '200 Years'
            T = 200;
        case '500 Years'
            T = 500;
        case '1000 Years'
            T = 1000;
    end

    % Define the folder path
folder_path = 'csv_frequency_analysis_files';
valuePP = 0;
% taking AMS data for station
for i = 1:3
    if app.plottingpositionCheckBox.Value == 0
        continue; % skip the loop
    end
    % Get the station name
    station_name = txt{indices(i)+2, 6};

    % Construct the file name based on tDropDown value
    switch app.DurationDropDown.Value
        case '10 min'
            file_name = ['x10_min_' station_name '.csv'];
        case '20 min'
            file_name = ['x20_min_' station_name '.csv'];
        case '30 min'
            file_name = ['x30_min_' station_name '.csv'];
        case '40 min'
            file_name = ['x40_min_' station_name '.csv'];
        case '50 min'
            file_name = ['x50_min_' station_name '.csv'];
        case '1 hr'
            file_name = ['x1_hr_' station_name '.csv'];
        case '1.5 hr'
            file_name = ['x1_5_hr_' station_name '.csv'];
        case '2 hr'
            file_name = ['x2_hr_' station_name '.csv'];
        case '2.5 hr'
            file_name = ['x2_5_hr_' station_name '.csv'];
        case '3 hr'
            file_name = ['x3_hr_' station_name '.csv'];
        case '3.5 hr'
            file_name = ['x3_5_hr_' station_name '.csv'];
        case '4 hr'
            file_name = ['x4_hr_' station_name '.csv'];
        case '4.5 hr'
            file_name = ['x4_5_hr_' station_name '.csv'];
        case '5 hr'
            file_name = ['x5_hr_' station_name '.csv'];
        case '6 hr'
            file_name = ['x6_hr_' station_name '.csv'];
    end

    % Create the file path
    file_path = fullfile(folder_path, file_name);

     % Read the data from the file, skipping the first row

    data_PP = readmatrix(file_path, 'HeaderLines', 1);

    % Extract column 5 and column 2 as separate arrays
    column_5 = data_PP(1:end, 5);
    column_2 = data_PP(1:end, 2);
       
    index_1 = find(column_5 == T, 1);
% cheaking if the return period is bigger then the years of observation for
% the station that generated by the coordiantes
            

     if isempty(index_1)
            % Number not found in column_5, perform linear interpolation
            if T > numel(column_5) + 1  % && app.plottingpositionCheckBox == 1
                error_msg = ['It is not possible to calculate the intensity of the rain using the Plotting Postion method at the frequency given at the ' station_name ' station. '];
                msgbox(error_msg, 'pay attention');
                fieldName = sprintf('app.plotting_postion%dEditField', i);
                eval([fieldName '.Visible = ''off'';']);
            else
                valuePP = interp1(column_5, column_2, T);
            end
     else
         valuePP = column_2(index_1);
     end
   % plotting the values for the 5th tab of the GUI for the plotting
   % position.
    if i == 1
        app.plotting_postion1EditField.Value = valuePP;
        scatter(app.UIAxes2,column_5,column_2,'MarkerFaceColor', 'blue', 'MarkerEdgeColor', 'blue');
        
        hold(app.UIAxes2, 'on');
        %hold(app.UIAxes2, 'off');
    elseif i == 2
        app.plotting_postion2EditField.Value = valuePP;
    elseif i == 3
        app.plotting_postion3EditField.Value = valuePP;
    end
end


% cheaking if atleast one of the cheakbox of the
% distributions is makred.
if (app.GumbleCheckBox.Value + app.LogPearsontypeIIICheckBox.Value + app.LogNormalCheckBox.Value + app.plottingpositionCheckBox.Value) == 0 
    msgbox('Must chose atleast 1 distributions method','Warning','modal');
    return;
end


 %cheaking if atleast one of the cheakbox of the
% adujusted calculatio ns is makred.

if (app.CheckBox_26.Value + app.CheckBox_27.Value + app.CheckBox_28.Value) == 0 
    msgbox('A method of calculating the average of the final intensity must be chosen','Warning','modal');
    return;
end


    % Arithmetic average
    % normal average calculation for the marked distributions
if app.GumbleCheckBox.Value + app.LogPearsontypeIIICheckBox.Value + app.LogNormalCheckBox.Value
    sum_all = (app.gumb1EditField.Value + app.gumb2EditField.Value + app.gumb3EditField.Value)*app.GumbleCheckBox.Value...
            + (app.logNormal1EditField.Value + app.logNormal2EditField.Value + app.logNormal3EditField.Value)*app.LogPearsontypeIIICheckBox.Value ...
            + (app.logPearson1EditField.Value + app.logPearson2EditField.Value + app.logPearson3EditField.Value)*app.LogNormalCheckBox.Value;
    avrg = sum_all/(3*(app.GumbleCheckBox.Value + app.LogPearsontypeIIICheckBox.Value  + app.LogNormalCheckBox.Value));
    app.AverageIntensityEditField.Value = avrg;
else
    app.AverageIntensityEditField.Value = 0;
    
end

    % weight of each station [IDW] and calcutions 
    d1 = app.DIS1EditField.Value + eps;
    d2 = app.DIS2EditField.Value;
    d3 = app.DIS3EditField.Value;
    weight_station = [1/(d1^2)/(1/(d1)^2 + 1/(d2)^2 + 1/(d3)^2) , 1/(d2^2)/(1/(d1)^2 + 1/(d2)^2 + 1/(d3)^2), 1/(d3^2)/(1/(d1)^2 + 1/(d2)^2 + 1/(d3)^2)];

    I_gumble = app.gumb1EditField.Value*weight_station(1) + app.gumb2EditField.Value*weight_station(2) + app.gumb3EditField.Value*weight_station(3);
    I_pearson = app.logPearson1EditField.Value*weight_station(1) +  app.logPearson2EditField.Value*weight_station(2) +app.logPearson3EditField.Value*weight_station(3);
    I_Normal = app.logNormal1EditField.Value*weight_station(1) + app.logNormal2EditField.Value*weight_station(2) + app.logNormal3EditField.Value*weight_station(3);
    
    selectedValues = [];

        if app.GumbleCheckBox.Value
            Value1 = I_gumble;
            selectedValues = [selectedValues, Value1];
        end
        
        if app.LogPearsontypeIIICheckBox.Value
            Value2 = I_pearson;
            selectedValues = [selectedValues, Value2];
        end
    
        if app.LogNormalCheckBox.Value
            Value3 = I_Normal;
            selectedValues = [selectedValues, Value3];
        end    
    
        % Calculate the average value
        switch numel(selectedValues)
            case 0
               averageValue = 0;
            case 1
                averageValue = selectedValues(1);
            otherwise
               averageValue = mean(selectedValues);
        end
    
        app.AverageIntensityIDWEditField.Value = averageValue;

    % Linear Interpolation calc

        A =  [data(indices(1:3), 2), data(indices(1:3), 1), ones(3, 1)];
        b_gumble = [app.gumb1EditField.Value; app.gumb2EditField.Value; app.gumb3EditField.Value];
        b_pearson = [app.logPearson1EditField.Value; app.logPearson2EditField.Value; app.logPearson3EditField.Value];
        b_Normal = [app.logNormal1EditField.Value; app.logNormal2EditField.Value; app.logNormal3EditField.Value];
        
        coaff = [inv(A)*b_gumble, inv(A)*b_pearson, inv(A)*b_Normal]; 
        
        Ig= coaff(1,1)*x1 + coaff(2,1)*y1 + coaff(3,1)*1;
        Ip= coaff(1,2)*x1 + coaff(2,2)*y1 + coaff(3,2)*1;
        In= coaff(1,3)*x1 + coaff(2,3)*y1 + coaff(3,3)*1;

        I_interp = [Ig , Ip , In ];
        
        
        % avrage between the differents distribution

        selectedinterpValues = [];

        if app.GumbleCheckBox.Value
            interpValue1 = I_interp(1);
            selectedinterpValues = [selectedinterpValues, interpValue1];
        end
        
        if app.LogPearsontypeIIICheckBox.Value
            interpValue2 = I_interp(2);
            selectedinterpValues = [selectedinterpValues, interpValue2];
        end
    
        if app.LogNormalCheckBox.Value
            interpValue3 = I_interp(3);
            selectedinterpValues = [selectedinterpValues, interpValue3];
        end    
            % Calculate the interp value
        switch numel(selectedinterpValues)
            case 0
               interpValue = 0;
            case 1
                interpValue = selectedinterpValues(1);
            otherwise
               interpValue = mean(selectedinterpValues);
        end
    
        app.LinearInterpolationEditField.Value = interpValue;
        
        % hide I_interp if exterpulation is happening
        x_max = max(A(:,1));
        x_min = min(A(:,1));
        y_max = max(A(:,2));
        y_min = min(A(:,2));

        if ((x_min < x1) && (x1 < x_max)) && ((y_min < y1) && (y1 < y_max)) 
            app.LinearInterpolationEditField.Visible = "on";
        else
            app.LinearInterpolationEditField.Visible = "off";
            msgbox('The Given Coordinate Is Outside the Interpolation Area','Warning','modal');
        end

            if app.CheckBox_26.Value + app.CheckBox_27.Value  + app.CheckBox_28.Value
    Intencity_sum = app.AverageIntensityEditField.Value*app.CheckBox_26.Value...
            + (app.AverageIntensityIDWEditField.Value)*app.CheckBox_27.Value ...
            + (app.LinearInterpolationEditField.Value)*app.CheckBox_28.Value;
    Final_I = Intencity_sum/(app.CheckBox_26.Value + app.CheckBox_27.Value  + app.CheckBox_28.Value +1e-10);
   app.FinalIntensitymmhrEditField_2.Value = ceil(Final_I);
      app.FinalIntensitymmhrEditField.Value = ceil(Final_I);

            end


        % Define the file paths
    gumbleFolderPath = 'Gumble_IDF';
    pearsonFolderPath = 'idf_tabels_log_pearson';
    normalFolderPath = 'log_normal_idf_tables';

    % Get the station name from the respective edit fields
    station1 = app.station1EditField.Value;
    station2 = app.station2EditField.Value;
    station3 = app.station3EditField.Value;

    % Create the matrices with their respective names and file paths for
    % the full table in tab 3.
    IDF_table_stn1_Gumble = readmatrix(fullfile(gumbleFolderPath, [station1 '_IDF_table.csv']));
    IDF_table_stn2_Gumble = readmatrix(fullfile(gumbleFolderPath, [station2 '_IDF_table.csv']));
    IDF_table_stn3_Gumble = readmatrix(fullfile(gumbleFolderPath, [station3 '_IDF_table.csv']));
    IDF_table_stn1_Pearson = readmatrix(fullfile(pearsonFolderPath, [station1 '_Pearson_type3_IDF.xls']),'DataRange','B2:J16');
    IDF_table_stn2_Pearson = readmatrix(fullfile(pearsonFolderPath, [station2 '_Pearson_type3_IDF.xls']),'DataRange','B2:J16');
    IDF_table_stn3_Pearson = readmatrix(fullfile(pearsonFolderPath, [station3 '_Pearson_type3_IDF.xls']),'DataRange','B2:J16');
    IDF_table_stn1_Normal = readmatrix(fullfile(normalFolderPath, [station1 '_z_normal.csv']));
    IDF_table_stn2_Normal = readmatrix(fullfile(normalFolderPath, [station2 '_z_normal.csv']));
    IDF_table_stn3_Normal = readmatrix(fullfile(normalFolderPath, [station3 '_z_normal.csv']));
    
    % Exclude the first row and column from matrices 1-3 and matrices 7-9
    IDF_table_stn1_Gumble = IDF_table_stn1_Gumble(2:end, 2:end);
    IDF_table_stn2_Gumble = IDF_table_stn2_Gumble(2:end, 2:end);
    IDF_table_stn3_Gumble = IDF_table_stn3_Gumble(2:end, 2:end);
    IDF_table_stn1_Normal = IDF_table_stn1_Normal(2:end, 2:end);
    IDF_table_stn2_Normal = IDF_table_stn2_Normal(2:end, 2:end);
    IDF_table_stn3_Normal = IDF_table_stn3_Normal(2:end, 2:end);

    average_IDF_table = (IDF_table_stn1_Gumble + IDF_table_stn2_Gumble + IDF_table_stn3_Gumble + IDF_table_stn1_Pearson + IDF_table_stn2_Pearson + IDF_table_stn3_Pearson + IDF_table_stn1_Normal + IDF_table_stn2_Normal + IDF_table_stn3_Normal) / 9;
    
   
    % Calculate IDF_table_IDW_Gumble
    IDF_table_IDW_Gumble = IDF_table_stn2_Gumble * weight_station(2) + ...
    IDF_table_stn1_Gumble * weight_station(1) + IDF_table_stn3_Gumble * weight_station(3);

    % Calculate IDF_table_IDW_Pearson
    IDF_table_IDW_Pearson = IDF_table_stn2_Pearson * weight_station(2) + ...
    IDF_table_stn1_Pearson * weight_station(1) + IDF_table_stn3_Pearson * weight_station(3);

    % Calculate IDF_table_IDW_Normal
    IDF_table_IDW_Normal = IDF_table_stn2_Normal * weight_station(2) + ...
    IDF_table_stn1_Normal * weight_station(1) + IDF_table_stn3_Normal * weight_station(3);
    
   % IDW_average_IDF_table = (IDF_table_IDW_Gumble + IDF_table_IDW_Pearson + IDF_table_IDW_Normal)/3;
    
    if app.GumbleCheckBox.Value + app.LogPearsontypeIIICheckBox.Value  + app.LogNormalCheckBox.Value
    IDW_sum_IDF_table = (IDF_table_IDW_Gumble)*app.GumbleCheckBox.Value...
            + (IDF_table_IDW_Pearson)*app.LogPearsontypeIIICheckBox.Value ...
            + (IDF_table_IDW_Normal)*app.LogNormalCheckBox.Value;
    IDW_average_IDF_table = IDW_sum_IDF_table/(app.GumbleCheckBox.Value + app.LogPearsontypeIIICheckBox.Value  + app.LogNormalCheckBox.Value + 1e-10);
   
    else
    IDW_average_IDF_table = [];
    end

    % IDF tables after interpulation

    IDF_table_interp_Gumble = zeros(15, 9);
    IDF_table_interp_Pearson= zeros(15, 9);
    IDF_table_interp_Normal = zeros(15, 9);

    for row = 1:15
        for col = 1:9
            b_gumble_table = [IDF_table_stn1_Gumble(row,col);IDF_table_stn2_Gumble(row,col);IDF_table_stn3_Gumble(row,col)];
            b_pearson_table = [IDF_table_stn1_Pearson(row,col);IDF_table_stn2_Pearson(row,col);IDF_table_stn3_Pearson(row,col)];
            b_Normal_table = [IDF_table_stn1_Normal(row,col);IDF_table_stn2_Normal(row,col);IDF_table_stn3_Normal(row,col)];
            
            coaff_table = [inv(A)*b_gumble_table, inv(A)*b_pearson_table, inv(A)*b_Normal_table]; 
            
            Ig = coaff_table(1, 1) * x1 + coaff_table(2, 1) * y1 + coaff_table(3, 1) * 1;
            Ip = coaff_table(1, 2) * x1 + coaff_table(2, 2) * y1 + coaff_table(3, 2) * 1;
            In = coaff_table(1, 3) * x1 + coaff_table(2, 3) * y1 + coaff_table(3, 3) * 1;
        
            % Assign calculated values to the corresponding matrices
            IDF_table_interp_Gumble(row, col) = Ig;
            IDF_table_interp_Pearson(row, col) = Ip;
            IDF_table_interp_Normal(row, col) = In;

        end
    end
        if app.GumbleCheckBox.Value + app.LogPearsontypeIIICheckBox.Value + app.LogNormalCheckBox.Value
            interp_sum_IDF_table = (IDF_table_interp_Gumble)*app.GumbleCheckBox.Value...
            + (IDF_table_interp_Pearson)*app.LogPearsontypeIIICheckBox.Value ...
            + (IDF_table_interp_Normal)*app.LogNormalCheckBox.Value;
    interp_average_IDF_table = interp_sum_IDF_table/(app.GumbleCheckBox.Value + app.LogPearsontypeIIICheckBox.Value  + app.LogNormalCheckBox.Value + 1e-10);
        end

           if app.CheckBox_26.Value + app.CheckBox_27.Value  + app.CheckBox_28.Value
    IDF_table_sum = average_IDF_table*app.CheckBox_26.Value...
            + IDW_average_IDF_table*app.CheckBox_27.Value ...
            + interp_average_IDF_table*app.CheckBox_28.Value;
    Final_IDF_table = IDF_table_sum/(app.CheckBox_26.Value + app.CheckBox_27.Value  + app.CheckBox_28.Value +1e-10);
            end
% Table plotting

    % Construct the full file path
    fileName = strcat(app.station1EditField.Value, '_Pearson_type3_IDF.xls');
    filePath = fullfile('idf_tabels_log_pearson', fileName);

    % Read the Excel file and display the data in the UITable
    columnLabels = {'2 Years', '5 Years', '10 Years', '25 Years', '50 Years', '100 Years', '200 Years', '500 Years', '1000 Years'};
    rowLabels = {'10 minutes', '20 minutes', '30 minutes', '40 minutes', '50 minutes', '1 hr', '1.5 hr', '2 hours', '2.5 hours', '3 hours', '3.5 hours', '4 hr', '4.5 hours', '5 hours', '6 hours'};
    
    % Create the cell array to populate the UITable
    tableData = cell(numel(rowLabels)+1, numel(columnLabels)+1);

    % Set the column labels
    tableData(1, 2:end) = columnLabels;

    % Set the row labels
    tableData(2:end, 1) = rowLabels;

    % Creating the table in tab 3.
    matrixData = Final_IDF_table;
    for row = 1:numel(rowLabels)
        for col = 1:numel(columnLabels)
            tableData{row+1, col+1} = char(sprintf("%.2f",round(matrixData(row, col),3)));

        end
    end

    % Update the UITable data
    app.UITable.Data = tableData;

    %plotting reutring period in the lat tab making strings for each
    %duration 
                return_period = [2,5,10,25,50,100,200,500,1000];
            switch app.DurationDropDown.Value
            case '10 min'
                t_index = 1;
            case '20 min'
                t_index = 2;
            case '30 min'
                t_index = 3;
            case '40 min'
                t_index = 4;
            case '50 min'
                t_index = 5;
            case '1 hr'
                t_index = 6;
            case '1.5 hr'
                t_index = 7;
            case '2 hr'
                t_index = 8;
            case '2.5 hr'
                t_index = 9;
            case '3 hr'
                t_index = 10;
            case '3.5 hr'
                t_index = 11;
            case '4 hr'
                t_index = 12;
            case '4.5 hr'
                t_index = 13;
            case '5 hr'
                t_index = 14;
            case '6 hr'
                t_index = 15;
                          
            end
    % plotting graph in tab 5. 
    plot(app.UIAxes2,return_period,matrixData(t_index,:),'MarkerFaceColor', 'red', 'MarkerEdgeColor', 'red','LineWidth',1.5);
    hold(app.UIAxes2, 'on');
    hold(app.UIAxes2, 'off');
    string1 = 'Average IDF for duration of';
    % changing title for graph for the given duration from tab 2.
    title = [string1, ' ',string2];

    app.UIAxes2.Title.String = title;
    legend(app.UIAxes2,'Plottion postion*','Average IDF ');


% plot idf tab 4.
    duration = [0.166 0.333 0.5 0.66 0.833 1 1.5 2 2.5 3 3.5 4 4.5 5 6];
    duration_trans = duration.';
    xi = linspace(0.166,6,1000); 
    for i = 1:9
        yi = interp1(duration_trans,IDW_average_IDF_table(:,i),xi,'linear');
        plot(app.UIAxes,xi,yi,'LineWidth',1.5);
        hold(app.UIAxes, 'on'); 
        
    end
    hold(app.UIAxes, 'off');  % Release the axes hold
    legend(app.UIAxes,'2 Years','5 Years', '10 Years', '25 Years', '50 Years ', ...
        '100 Years', '200 Years', '500 Years', '1000 Years');
       
    
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % convert the field text to a number % the fields for
            % parameters
            H = str2double(app.HmEditField.Value) ;
            length = str2double(app.LmEditField.Value)/1000;
            %slope = avg of main watercourse slope (flow path)
            slope_c = str2double(app.SEditField.Value);
            A = str2double(app.Akm2EditField.Value);
            % DeltaH =is the diffrence between the maximum elevation of the whole catchment and the outlet section.
            DeltaH = str2double(app.DeltaHmEditField.Value);
            % C = unoff coefficient of the rational method
            C = str2double(app.CEditField.Value);
            % Slope_AVG = average steepness of the entire catchament 
            slope_AVG = str2double(app.SlopemeanEditField.Value);
            % CN = runoff cruve number ( For NRCS-SCS method)
            CN = str2double(app.CNEditField.Value);
            % r is a branching factor varies from 1 to 2 in Eaton's method
            r = str2double(app.rEditField.Value);

%%%%%%%%%%%%%%%%%%%%
           
            
            % T_c Formula's
            BransbyWilliamsModel = 0.2426*length*A^(-0.1)*(slope_c+eps)^(-0.2);
                        
            GiandottisModel = (4*sqrt(A)+1.5*length)/(0.8*sqrt(H+eps));

            KirpichModel = 0.0663*length^0.77*(slope_c+eps)^(-0.385);

            JohnstoneAndCrossModel = 0.0543*length^0.5*(slope_c+eps)^(-0.5);
            
            CaliforniaCulvertPracticeMethodology = 0.95*length^1.155*(DeltaH+eps)^(-0.385);
            
            FederalAviationAgency = 0.3788*(1.1-C)*length^0.5*(slope_AVG+eps)^(-0.333);
   
            NRCSSCSmethod = 0.057*((1000/(CN+eps))-9)^0.7*length^0.8*(slope_AVG+eps)^(-0.5);

            % Texsas department of Transportaion is the same as FAA formula
            TexsasDepartmentOfTransportationFormula = 0.0369986*(1.1-C)*length^0.5*(slope_AVG+eps)^(-0.333);
            
            VenturaFormula = 0.1272*A^0.5*(slope_c+eps)^(-0.5);
         
            PasinisFormula = 0.108*A^0.333*length^0.333*(slope_AVG+eps)^(-0.5);

            TournonsFormula = 0.396*length*(slope_c+eps)^(-0.5)*(A*slope_c^0.5*length^(-2)*slope_AVG^(-0.5))^0.72;
            
            PuglisiAndZanframundosFormula = 6*length^0.666*DeltaH^(-0.333);
            
            FattorelliAndMarchisFormula = 5.13*length^0.666*DeltaH^(-0.333);
            
            PezzolisFormula = 0.055*length*(slope_c+eps)^(-0.5);
            
            EatonsMethod = 0.796074*A^0.37*length^0.037*r^(-0.37);
            
            SheridansFormula = 0.39*length^0.72*(slope_c+eps)^(-0.36);
            
            TemezsFormula = 0.3*length^0.76*(slope_c+eps)^(-0.19);
            
            ChowsModel = 0.1602*length^0.64*(slope_c+eps)^(-0.32);
            
            DoogesFormula = 0.365*A^0.41*(slope_c+eps)^(-0.17);
            
            HatkanirAndSezen = 0.7473*length^0.841;
            
            EspeysMethod = 0.381088*length^0.36*(slope_c+eps)^(-0.18);

            PilgrimAndMcDermotts = 0.76*A^0.38;
            
            FerroModel = 0.675*A^0.5;
            
            BocchiolaModel = 0.44833*((1000/(CN+eps))-9)^0.127*length^0.815*(slope_AVG+eps)^(-0.216);
               
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

             % hidding the fields if cheack box is unmarked   
            if ~isnan(BransbyWilliamsModel)
                app.BransbyWilliamssModelEditField.Value = BransbyWilliamsModel;
                set(app.BransbyWilliamssModelEditField, 'Visible', 'on');
                else
                BransbyWilliamsModel = 0;
                set(app.BransbyWilliamssModelEditField, 'Visible', 'off');
            end

            if ~isnan(GiandottisModel)
                app.GiandottismodelEditField.Value = GiandottisModel;
                set(app.GiandottismodelEditField, 'Visible', 'on');
            else
                GiandottisModel = 0;
                set(app.GiandottismodelEditField, 'Visible', 'off');
            end

            if ~isnan(KirpichModel)
                app.KirpichsModelEditField.Value = KirpichModel;
                set(app.KirpichsModelEditField, 'Visible', 'on');

                else
                KirpichModel = 0;
                set(app.KirpichsModelEditField, 'Visible', 'off');
            end

            if ~isnan(JohnstoneAndCrossModel)
                app.JohnstoneandCrosssmodelEditField.Value = JohnstoneAndCrossModel;
                set(app.JohnstoneandCrosssmodelEditField, 'Visible', 'on');
                else
                JohnstoneAndCrossModel = 0;
                set(app.JohnstoneandCrosssmodelEditField, 'Visible', 'off');
            end

            if ~isnan(CaliforniaCulvertPracticeMethodology)
                app.CaliforniaCulvertPracticesMethodologyEditField.Value = CaliforniaCulvertPracticeMethodology;
                set(app.CaliforniaCulvertPracticesMethodologyEditField, 'Visible', 'on');
                else
                CaliforniaCulvertPracticeMethodology = 0;
                set(app.CaliforniaCulvertPracticesMethodologyEditField, 'Visible', 'off');
            end

            if ~isnan(FederalAviationAgency)
                app.FederalAviationAgencysFormulaEditField.Value = FederalAviationAgency;
                set(app.FederalAviationAgencysFormulaEditField, 'Visible', 'on');
                else
                FederalAviationAgency = 0;
                set(app.FederalAviationAgencysFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(NRCSSCSmethod)
                app.NRCSSCSmethodEditField.Value = NRCSSCSmethod;
                set(app.NRCSSCSmethodEditField, 'Visible', 'on');
                else
                NRCSSCSmethod = 0;
                set(app.NRCSSCSmethodEditField, 'Visible', 'off');
            end

            if ~isnan(TexsasDepartmentOfTransportationFormula)
                app.TexasDepartmentoftransportationsformulaEditField.Value = TexsasDepartmentOfTransportationFormula;
                set(app.TexasDepartmentoftransportationsformulaEditField, 'Visible', 'on');
                else
                TexsasDepartmentOfTransportationFormula = 0;
                set(app.TexasDepartmentoftransportationsformulaEditField, 'Visible', 'off');
            end

            if ~isnan(VenturaFormula)
                app.VenturasFormulaEditField.Value = VenturaFormula;
                set(app.VenturasFormulaEditField, 'Visible', 'on');
                else
                VenturaFormula = 0;
                set(app.VenturasFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(PasinisFormula)
                app.PasinisFormulaEditField.Value = PasinisFormula;
                set(app.PasinisFormulaEditField, 'Visible', 'on');
                else
                PasinisFormula = 0;
                set(app.PasinisFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(TournonsFormula)
                app.TournonsFormulaEditField.Value = TournonsFormula;
                set(app.TournonsFormulaEditField, 'Visible', 'on');
                else
                TournonsFormula = 0;
                set(app.TournonsFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(PuglisiAndZanframundosFormula)
                app.PuglisiandZanframundosFormulaEditField.Value = PuglisiAndZanframundosFormula;
                set(app.PuglisiandZanframundosFormulaEditField, 'Visible', 'on');
                else
                PuglisiAndZanframundosFormula = 0;
                set(app.PuglisiandZanframundosFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(FattorelliAndMarchisFormula)
                app.FattorelliandMarchisFormulaEditField.Value = FattorelliAndMarchisFormula;
                set(app.FattorelliandMarchisFormulaEditField, 'Visible', 'on');
                else
                FattorelliAndMarchisFormula = 0;
                set(app.FattorelliandMarchisFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(PezzolisFormula)
                app.PezzolisFormulaEditField.Value = PezzolisFormula;
                set(app.PezzolisFormulaEditField, 'Visible', 'on');
                else
                PezzolisFormula = 0;
                set(app.PezzolisFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(EatonsMethod)
                app.EatonsMethodEditField.Value = EatonsMethod;
                set(app.EatonsMethodEditField, 'Visible', 'on');
                else
                EatonsMethod = 0;
                set(app.EatonsMethodEditField, 'Visible', 'off');
            end

            if ~isnan(SheridansFormula)
                app.SheridansFormulaEditField.Value = SheridansFormula;
                set(app.SheridansFormulaEditField, 'Visible', 'on');
                else
                SheridansFormula = 0;
                set(app.SheridansFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(TemezsFormula)
                app.TmezsFormulaEditField.Value = TemezsFormula;
                set(app.TmezsFormulaEditField, 'Visible', 'on');
                else
                TemezsFormula = 0;
                set(app.TmezsFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(ChowsModel)
                app.ChowsModelEditField.Value = ChowsModel;
                set(app.ChowsModelEditField, 'Visible', 'on');
                else
                ChowsModel = 0;
                set(app.ChowsModelEditField, 'Visible', 'off');
            end

            if ~isnan(DoogesFormula)
                 app.DoogesFormulaEditField.Value = DoogesFormula;
                set(app.DoogesFormulaEditField, 'Visible', 'on');
                else
                DoogesFormula = 0;
                set(app.DoogesFormulaEditField, 'Visible', 'off');

            end

            if ~isnan(HatkanirAndSezen)
                app.HatkanirandSezenEditField.Value = HatkanirAndSezen;
                set(app.HatkanirandSezenEditField, 'Visible', 'on');
                else
                HatkanirAndSezen = 0;
                set(app.HatkanirandSezenEditField, 'Visible', 'off');
            end

            if ~isnan(EspeysMethod)
                app.EspeysMethodEditField.Value = EspeysMethod;
                set(app.EspeysMethodEditField, 'Visible', 'on');
                else
                EspeysMethod = 0;
                set(app.EspeysMethodEditField, 'Visible', 'off');
            end

            if ~isnan(PilgrimAndMcDermotts)
               app.PilgrimandMcDermottsFormulaEditField.Value = PilgrimAndMcDermotts;
                set(app.PilgrimandMcDermottsFormulaEditField, 'Visible', 'on');
                else
                PilgrimAndMcDermotts = 0;
                set(app.PilgrimandMcDermottsFormulaEditField, 'Visible', 'off');
            end

            if ~isnan(FerroModel)
                app.FerrosModelEditField.Value = FerroModel;
                set(app.FerrosModelEditField, 'Visible', 'on');
                else
                FerroModel = 0;
                set(app.FerrosModelEditField, 'Visible', 'off');
            end

            if ~isnan(BocchiolaModel)
                app.BocchiolasModelEditField.Value = BocchiolaModel;
                set(app.BocchiolasModelEditField, 'Visible', 'on');
                else
                BocchiolaModel = 0;
                set(app.BocchiolasModelEditField, 'Visible', 'off');
            end
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % cheaking if atleast 1 of the cheak box is marked.
         if (app.CheckBox.Value + app.CheckBox_2.Value  + app.CheckBox_3.Value+...
            app.CheckBox_4.Value + app.CheckBox_5.Value  + app.CheckBox_6.Value +...
            app.CheckBox_7.Value + app.CheckBox_8.Value  + app.CheckBox_9.Value+...
            app.CheckBox_10.Value + app.CheckBox_11.Value  + app.CheckBox_12.Value+...
            app.CheckBox_13.Value + app.CheckBox_14.Value  + app.CheckBox_15.Value+...
            app.CheckBox_16.Value + app.CheckBox_17.Value  + app.CheckBox_18.Value+...
            app.CheckBox_19.Value + app.CheckBox_20.Value  + app.CheckBox_21.Value+...
            app.CheckBox_22.Value + app.CheckBox_23.Value  + app.CheckBox_24.Value +...
            app.CheckBox_25.Value ) == 0 
        msgbox('A formula for calculating the time of concentration must be chosen','Select a formula to continue','modal');
        return;
    end
% calculating the average for the Tc
    if app.CheckBox.Value + app.CheckBox_2.Value  + app.CheckBox_3.Value+...
            app.CheckBox_4.Value + app.CheckBox_5.Value  + app.CheckBox_6.Value +...
            app.CheckBox_7.Value + app.CheckBox_8.Value  + app.CheckBox_9.Value+...
            app.CheckBox_10.Value + app.CheckBox_11.Value  + app.CheckBox_12.Value+...
            app.CheckBox_13.Value + app.CheckBox_14.Value  + app.CheckBox_15.Value+...
            app.CheckBox_16.Value + app.CheckBox_17.Value  + app.CheckBox_18.Value+...
            app.CheckBox_19.Value + app.CheckBox_20.Value  + app.CheckBox_21.Value+...
            app.CheckBox_22.Value + app.CheckBox_23.Value  + app.CheckBox_24.Value +...
            app.CheckBox_25.Value 

    SumTc = app.CheckBox.Value * BransbyWilliamsModel + app.CheckBox_2.Value * GiandottisModel +...
            app.CheckBox_3.Value * KirpichModel + app.CheckBox_4.Value* JohnstoneAndCrossModel +...
            app.CheckBox_5.Value * CaliforniaCulvertPracticeMethodology + app.CheckBox_6.Value* FederalAviationAgency+...
            app.CheckBox_7.Value * NRCSSCSmethod + app.CheckBox_8.Value* TexsasDepartmentOfTransportationFormula  +...
            app.CheckBox_9.Value * VenturaFormula + app.CheckBox_10.Value* PasinisFormula +...
            app.CheckBox_11.Value* TournonsFormula + app.CheckBox_12.Value*PuglisiAndZanframundosFormula +...
            app.CheckBox_13.Value* app.AddmanuallyEditField.Value + app.CheckBox_14.Value*FattorelliAndMarchisFormula +...
            app.CheckBox_15.Value* PezzolisFormula + app.CheckBox_16.Value*EatonsMethod +...
            app.CheckBox_17.Value* SheridansFormula + app.CheckBox_18.Value*TemezsFormula +...
            app.CheckBox_19.Value* ChowsModel + app.CheckBox_20.Value*DoogesFormula +...
            app.CheckBox_21.Value* HatkanirAndSezen + app.CheckBox_22.Value*EspeysMethod +...
            app.CheckBox_23.Value* PilgrimAndMcDermotts+ app.CheckBox_24.Value*FerroModel +...
            app.CheckBox_25.Value*BocchiolaModel;

     AverageTc = SumTc/(app.CheckBox.Value + app.CheckBox_2.Value  + app.CheckBox_3.Value+...
            app.CheckBox_4.Value + app.CheckBox_5.Value  + app.CheckBox_6.Value +...
            app.CheckBox_7.Value + app.CheckBox_8.Value  + app.CheckBox_9.Value+...
            app.CheckBox_10.Value + app.CheckBox_11.Value  + app.CheckBox_12.Value+...
            app.CheckBox_13.Value + app.CheckBox_14.Value  + app.CheckBox_15.Value+...
            app.CheckBox_16.Value + app.CheckBox_17.Value  + app.CheckBox_18.Value+...
            app.CheckBox_19.Value + app.CheckBox_20.Value  + app.CheckBox_21.Value+...
            app.CheckBox_22.Value + app.CheckBox_23.Value  + app.CheckBox_24.Value +...
            app.CheckBox_25.Value );
   
    end
    
    app.AverageTcHoursEditField.Value = AverageTc;
    app.AverageTcHoursEditField_2.Value = app.AverageTcHoursEditField.Value;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: ComputeButton_2
        function ComputeButton_2Pushed(app, event)
        % Qp calculator rational method.
        C = str2double(app.CEditField_2.Value);
        I = app.FinalIntensitymmhrEditField_2.Value;
        A = str2double(app.AdunamEditField_2.Value);
        
        Qp = (C*I*A);
        app.Qpm3hrEditField.Value = Qp;

        end

        % Value changed function: GumbleCheckBox
        function GumbleCheckBoxValueChanged(app, event)
            value = app.GumbleCheckBox.Value;
        if value
            app.gumb1EditField.Visible = 'on';
            app.gumb2EditField.Visible = 'on';
            app.gumb3EditField.Visible = 'on';
        else
            app.gumb1EditField.Visible = 'off';
            app.gumb2EditField.Visible = 'off';
            app.gumb3EditField.Visible = 'off';
        end

     
        end

        % Value changed function: plottingpositionCheckBox
        function plottingpositionCheckBoxValueChanged(app, event)
            value = app.plottingpositionCheckBox.Value;
            % hiding the fields if cheack box is un-marked after it been
            % marked
        if value
            app.plotting_postion1EditField.Visible = 'on';
            app.plotting_postion2EditField.Visible = 'on';
            app.plotting_postion3EditField.Visible = 'on';
        else
            app.plotting_postion1EditField.Visible = 'off';
            app.plotting_postion2EditField.Visible = 'off';
            app.plotting_postion3EditField.Visible = 'off';
        end
        end

        % Value changed function: LogNormalCheckBox
        function LogNormalCheckBoxValueChanged(app, event)
            value = app.LogNormalCheckBox.Value;
            % hiding the fields if cheack box is un-marked after it been
            % marked
         if value
            app.logNormal1EditField.Visible = 'on';
            app.logNormal2EditField.Visible = 'on';
            app.logNormal3EditField.Visible = 'on';
        else
            app.logNormal1EditField.Visible = 'off';
            app.logNormal2EditField.Visible = 'off';
            app.logNormal3EditField.Visible = 'off';
        end            
        end

        % Value changed function: LogPearsontypeIIICheckBox
        function LogPearsontypeIIICheckBoxValueChanged(app, event)
            value = app.LogPearsontypeIIICheckBox.Value;
            % hiding the fields if cheack box is un-marked after it been
            % marked
        if value
            app.logPearson1EditField.Visible = 'on';
            app.logPearson2EditField.Visible = 'on';
            app.logPearson3EditField.Visible = 'on';
        else
            app.logPearson1EditField.Visible = 'off';
            app.logPearson2EditField.Visible = 'off';
            app.logPearson3EditField.Visible = 'off';
        end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
%             data_editFields = [app.XEditField.Value, app.YEditField.Value, app.station1EditField.Value, app.station2EditField.Value, app.station3EditField.Value, app.DIS1EditField.Value, app.DIS2EditField.Value, app.DIS3EditField.Value, app.gumb1EditField.Value, app.gumb2EditField.Value, app.gumb3EditField.Value, app.logPearson1EditField.Value, app.logPearson2EditField.Value, app.logPearson3EditField.Value, app.logNormal1EditField.Value, app.logNormal2EditField.Value, app.logNormal3EditField.Value, app.plotting_postion1EditField.Value, app.plotting_postion2EditField.Value, app.plotting_postion3EditField.Value, app.AverageIntensityEditField.Value, app.AverageIntensityIDWEditField.Value, app.LinearInterpolationEditField.Value];
%             set(data_editFields, '');
% cleaning the fields. by pressing "Clear"
        app.XEditField.Value = '';
    app.YEditField.Value = '';
    app.station1EditField.Value = '';
    app.station2EditField.Value = '';
    app.station3EditField.Value = '';
    app.DIS1EditField.Value = 0;
    app.DIS2EditField.Value = 0;
    app.DIS3EditField.Value = 0;
    app.gumb1EditField.Value = 0;
    app.gumb2EditField.Value = 0;
    app.gumb3EditField.Value = 0;
    app.logPearson1EditField.Value = 0;
    app.logPearson2EditField.Value = 0;
    app.logPearson3EditField.Value = 0;
    app.logNormal1EditField.Value = 0;
    app.logNormal2EditField.Value = 0;
    app.logNormal3EditField.Value = 0;
    app.plotting_postion1EditField.Value = 0;
    app.plotting_postion2EditField.Value = 0;
    app.plotting_postion3EditField.Value = 0;
    app.AverageIntensityEditField.Value = 0;
    app.AverageIntensityIDWEditField.Value = 0;
    app.LinearInterpolationEditField.Value = 0;
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)
%             scan_editFields = [app.HmEditField.Value, app.LmEditField.Value, app.SEditField.Value, app.Akm2EditField.Value, app.DeltaHmEditField.Value, app.SlopemeanEditField.Value, app.rEditField.Value, app.CEditField.Value, app.CNEditField.Value];
%             set(scan_editFields, '');
% cleaning the fields. by pressing "Clear"
            app.HmEditField.Value = '';
            app.LmEditField.Value = '';
            app.SEditField.Value = '';
            app.Akm2EditField.Value = '';
            app.DeltaHmEditField.Value = '';
            app.SlopemeanEditField.Value = '';
            app.rEditField.Value = '';
            app.CEditField.Value = '';
            app.CNEditField.Value = '';
            set(app.BransbyWilliamssModelEditField, 'Visible', 'off');
            set(app.GiandottismodelEditField, 'Visible', 'off');
            set(app.KirpichsModelEditField, 'Visible', 'off');
            set(app.JohnstoneandCrosssmodelEditField, 'Visible', 'off');
            set(app.CaliforniaCulvertPracticesMethodologyEditField, 'Visible', 'off');
            set(app.FederalAviationAgencysFormulaEditField, 'Visible', 'off');
            set(app.NRCSSCSmethodEditField, 'Visible', 'off');
            set(app.TexasDepartmentoftransportationsformulaEditField, 'Visible', 'off');
            set(app.VenturasFormulaEditField, 'Visible', 'off');
            set(app.PasinisFormulaEditField, 'Visible', 'off');
            set(app.TournonsFormulaEditField, 'Visible', 'off');
            set(app.PuglisiandZanframundosFormulaEditField, 'Visible', 'off');
            set(app.FattorelliandMarchisFormulaEditField, 'Visible', 'off');
            set(app.PezzolisFormulaEditField, 'Visible', 'off');
            set(app.EatonsMethodEditField, 'Visible', 'off');
            set(app.SheridansFormulaEditField, 'Visible', 'off');
            set(app.TmezsFormulaEditField, 'Visible', 'off');
            set(app.ChowsModelEditField, 'Visible', 'off');
            set(app.DoogesFormulaEditField, 'Visible', 'off');
            set(app.HatkanirandSezenEditField, 'Visible', 'off');
            set(app.EspeysMethodEditField, 'Visible', 'off');
            set(app.PilgrimandMcDermottsFormulaEditField, 'Visible', 'off');
            set(app.FerrosModelEditField, 'Visible', 'off');
            set(app.BocchiolasModelEditField, 'Visible', 'off');
            app.AddmanuallyEditField.Value = 0;
            app.AverageTcHoursEditField_2.Value = 0; 
        end

        % Callback function
        function Button2Pushed(app, event)
          
        end

        % Callback function
        function Hyperlink2Clicked(app, event)
         
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.CEditField_2.Value = '';
            % cleaning the fields. by pressing "Clear"

            app.AdunamEditField_2.Value = '';
            app.Qpm3hrEditField.Value = 0;
        end

        % Value changed function: HmEditField
        function HmEditFieldValueChanged(app, event)

        end

        % Value changed function: LmEditField
        function LmEditFieldValueChanged(app, event)

        end

        % Value changed function: SEditField
        function SEditFieldValueChanged(app, event)

        end

        % Value changed function: Akm2EditField
        function Akm2EditFieldValueChanged(app, event)


        end

        % Value changed function: DeltaHmEditField
        function DeltaHmEditFieldValueChanged(app, event)

        end

        % Value changed function: SlopemeanEditField
        function SlopemeanEditFieldValueChanged(app, event)
    
        end

        % Value changed function: rEditField
        function rEditFieldValueChanged(app, event)
         
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
           
        end

        % Value changed function: CNEditField
        function CNEditFieldValueChanged(app, event)
         
        end

        % Button pushed function: ExportDatatoxlsxButton
        function ExportDatatoxlsxButtonPushed(app, event)
                        % Create a cell array with the data
            data_export = {'X', 'Y', '', 'Average Tc [Hours]', '', '';...
                string(app.XEditField.Value), string(app.YEditField.Value), '', string(app.AverageTcHoursEditField.Value),... 
                '', '';...
                'Station names', 'Distance [km]','Gumbel [mm\hr]', 'Log Pearson type III [mm\hr]','Log Normal [mm\hr]','Plotting Postion [mm\hr]'; ...
                string(app.station1EditField.Value), string(app.DIS1EditField.Value) , string(app.gumb1EditField.Value) , string(app.logPearson1EditField.Value) ,string(app.logNormal1EditField.Value), string(app.plotting_postion1EditField.Value);...
                string(app.station2EditField.Value), string(app.DIS2EditField.Value) , string(app.gumb2EditField.Value) , string(app.logPearson2EditField.Value) ,string(app.logNormal2EditField.Value), string(app.plotting_postion2EditField.Value);...
                string(app.station3EditField.Value), string(app.DIS3EditField.Value) , string(app.gumb3EditField.Value) , string(app.logPearson3EditField.Value) ,string(app.logNormal3EditField.Value), string(app.plotting_postion3EditField.Value);...
                '', '', '', '', '', '';...
                'Average Intensity [mm\hr]', string(app.AverageIntensityEditField.Value), '' , 'Runoff coefficent [-]', string(app.CEditField_2.Value), '';...
                'Average Intensity [IDW] [mm\hr]', string(app.AverageIntensityIDWEditField.Value), '', 'Final Intensity [mm\hr]', string(app.FinalIntensitymmhrEditField_2.Value), '';...
                'Linear Interpolation [mm\hr]', string(app.LinearInterpolationEditField.Value), '', 'A [dunam]', string(app.AdunamEditField_2.Value), '';...
                '', '', '', 'Q [m^3\hr]', string(app.Qpm3hrEditField.Value), ''};


            % Export the data to an Excel file

            [filename, folder] = uiputfile('*.xlsx');
            writecell(data_export,fullfile(folder,filename))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FluvalCalcUIFigure = uifigure('Visible', 'off');
            app.FluvalCalcUIFigure.Name = 'FluvalCalc';
            app.FluvalCalcUIFigure.Icon = fullfile(pathToMLAPP, 'Distance_Intencity_2_resources', 'icon_24.png');
            app.TabGroup = uitabgroup(app.FluvalCalcUIFigure);
            app.TimeofConcentrationTab = uitab(app.TabGroup);
            app.TimeofConcentrationTab.Title = '1.Time of Concentration';
            app.ComputeButton = uibutton(app.TimeofConcentrationTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.HmEditFieldLabel = uilabel(app.TimeofConcentrationTab);
            app.HmEditFieldLabel.Text = 'H [m]';
            app.HmEditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.HmEditField.ValueChangedFcn = createCallbackFcn(app, @HmEditFieldValueChanged, true);
            app.DeltaHmEditFieldLabel = uilabel(app.TimeofConcentrationTab);
            app.DeltaHmEditFieldLabel.Text = 'DeltaH[m]';
            app.DeltaHmEditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.DeltaHmEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaHmEditFieldValueChanged, true);
            app.CEditFieldLabel = uilabel(app.TimeofConcentrationTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.SlopemeanLabel = uilabel(app.TimeofConcentrationTab);
            app.SlopemeanLabel.Text = 'Slope mean';
            app.SlopemeanEditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.SlopemeanEditField.ValueChangedFcn = createCallbackFcn(app, @SlopemeanEditFieldValueChanged, true);
            app.CNEditFieldLabel = uilabel(app.TimeofConcentrationTab);
            app.CNEditFieldLabel.Text = 'CN';
            app.CNEditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.CNEditField.ValueChangedFcn = createCallbackFcn(app, @CNEditFieldValueChanged, true);
            app.rEditFieldLabel = uilabel(app.TimeofConcentrationTab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.rEditField.ValueChangedFcn = createCallbackFcn(app, @rEditFieldValueChanged, true);
            app.LmEditFieldLabel = uilabel(app.TimeofConcentrationTab);
            app.LmEditFieldLabel.Text = 'L [m]';
            app.LmEditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.LmEditField.ValueChangedFcn = createCallbackFcn(app, @LmEditFieldValueChanged, true);
            app.SEditFieldLabel = uilabel(app.TimeofConcentrationTab);
            app.SEditFieldLabel.Text = 'S [-]';
            app.SEditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.SEditField.ValueChangedFcn = createCallbackFcn(app, @SEditFieldValueChanged, true);
            app.Akm2EditFieldLabel = uilabel(app.TimeofConcentrationTab);
            app.Akm2EditFieldLabel.Text = 'A [km^2]';
            app.Akm2EditField = uieditfield(app.TimeofConcentrationTab, 'text');
            app.Akm2EditField.ValueChangedFcn = createCallbackFcn(app, @Akm2EditFieldValueChanged, true);
            app.Panel = uipanel(app.TimeofConcentrationTab);
            app.BransbyWilliamssModelEditFieldLabel = uilabel(app.Panel);
            app.BransbyWilliamssModelEditFieldLabel.Tooltip = {'This Formula  is derived from observations of floods in India in rural basins and is suitable for areas smaller than 129.5 [km^2]'};
            app.BransbyWilliamssModelEditFieldLabel.Text = 'Bransby-Williams’s Model';
            app.BransbyWilliamssModelEditField = uieditfield(app.Panel, 'numeric');
            app.BransbyWilliamssModelEditField.Visible = 'off';
            app.BransbyWilliamssModelEditField.Tooltip = {'This Formula  is derived from observations of floods in India in rural basins and is suitable for areas smaller than 129.5 [km^2]'; 'Paramters needed: L = Length of main watercourse channel [m]; A= cathment area in [km^2 ];'; 'S = average main watercourse slope [mm^(-1)]'};
            app.GiandottismodelEditFieldLabel = uilabel(app.Panel);
            app.GiandottismodelEditFieldLabel.Tooltip = {'Giandotti''s model formula was developed for 12 watershed areas in central and northern Italy, and it is recommended to use this formula for basins in size range of 170-70,000 km^2'};
            app.GiandottismodelEditFieldLabel.Text = 'Giandotti’s model';
            app.GiandottismodelEditField = uieditfield(app.Panel, 'numeric');
            app.GiandottismodelEditField.Visible = 'off';
            app.GiandottismodelEditField.Tooltip = {'Giandotti''s model formula was developed for 12 watershed areas in central and northern Italy, and it is recommended to use this formula for basins in size range of 170-70,000 km^2'; 'Paramters needed: L = Length of main watercourse channel [m]; A= cathment area in [km^2 ];'; 'H = Diffrence between the mean elevation of the whole catchment and the outlet section.'};
            app.KirpichsModelEditFieldLabel = uilabel(app.Panel);
            app.KirpichsModelEditFieldLabel.Tooltip = {'The formula was developed by Kirpich by improving the formula from Ramezer, by collecting data in small agricultural drainage basins in Tennessee and Pennsylvania in the USA, the measurements were taken in an area ranging from 0.004 to 0.453 square kilometers, and an average slope of 3% to 10%, McConnen, tested the accuracy of the method in urban areas with sizes of 8-16 square kilometers, and obtained results in the estimation of concentration time with a deviation of 75% from Kirpich''s method.'};
            app.KirpichsModelEditFieldLabel.Text = 'Kirpich’s Model';
            app.KirpichsModelEditField = uieditfield(app.Panel, 'numeric');
            app.KirpichsModelEditField.Visible = 'off';
            app.KirpichsModelEditField.Tooltip = {'The formula was developed by Kirpich by improving the formula from Ramezer, by collecting data in small agricultural drainage basins in Tennessee and Pennsylvania in the USA, the measurements were taken in an area ranging from 0.004 to 0.453 square kilometers, and an average slope of 3% to 10%, McConnen, tested the accuracy of the method in urban areas with sizes of 8-16 square kilometers, and obtained results in the estimation of concentration time with a deviation of 75% from Kirpich''s method.'; 'Paramters needed: L = Length of main watercourse channel [m]; '; 'S = average main watercourse slope [mm^(-1)]'};
            app.JohnstoneandCrosssmodelEditFieldLabel = uilabel(app.Panel);
            app.JohnstoneandCrosssmodelEditFieldLabel.Tooltip = {'This formula was developed for 19 sub-drainage basins, located in the USA in Ohio. The size range of the basins is between 65 and 4,206 square kilometers.'};
            app.JohnstoneandCrosssmodelEditFieldLabel.Text = 'Johnstone and Cross’s model ';
            app.JohnstoneandCrosssmodelEditField = uieditfield(app.Panel, 'numeric');
            app.JohnstoneandCrosssmodelEditField.Visible = 'off';
            app.JohnstoneandCrosssmodelEditField.Tooltip = {'This formula was developed for 19 sub-drainage basins, located in the USA in Ohio. The size range of the basins is between 65 and 4,206 square kilometers.'; 'Paramters needed: L = Length of main watercourse channel [m];'; 'S = average main watercourse slope [mm^(-1)]'};
            app.CaliforniaCulvertPracticesMethodologyEditFieldLabel = uilabel(app.Panel);
            app.CaliforniaCulvertPracticesMethodologyEditFieldLabel.Tooltip = {'The formula is a modification of Kirpich''s model, and was developed especially for small mountain basins in California with a drainage area of ​​up to 40.47 square kilometers'};
            app.CaliforniaCulvertPracticesMethodologyEditFieldLabel.Text = 'California Culvert Practice’s Methodology';
            app.CaliforniaCulvertPracticesMethodologyEditField = uieditfield(app.Panel, 'numeric');
            app.CaliforniaCulvertPracticesMethodologyEditField.Visible = 'off';
            app.CaliforniaCulvertPracticesMethodologyEditField.Tooltip = {'The formula is a modification of Kirpich''s model, and was developed especially for small mountain basins in California with a drainage area of ​​up to 40.47 square kilometers'; 'Paramters needed: L = Length of main watercourse channel [m]; Delta_H = its the diffrence between the maximum elevation of the whole cathment and the outlet section'};
            app.FederalAviationAgencysFormulaEditFieldLabel = uilabel(app.Panel);
            app.FederalAviationAgencysFormulaEditFieldLabel.Tooltip = {'This method was developed with the help of airport drainage data by engineers from the US Army, and is suitable for drainage basins smaller than 0.50 square kilometers, and with a uniform surface, and the flow regime is controlled by concentrated and shallow flows.'};
            app.FederalAviationAgencysFormulaEditFieldLabel.Text = 'Federal Aviation Agency’s Formula';
            app.FederalAviationAgencysFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.FederalAviationAgencysFormulaEditField.Visible = 'off';
            app.FederalAviationAgencysFormulaEditField.Tooltip = {'This method was developed with the help of airport drainage data by engineers from the US Army, and is suitable for drainage basins smaller than 0.50 square kilometers, and with a uniform surface, and the flow regime is controlled by concentrated and shallow flows.'; 'Paramters needed: L = Length of main watercourse channel [m]; A= cathment area in [km^2 ];'; 'S = average main watercourse slope [mm^(-1)], C = runoff coefficient of the rational method'};
            app.NRCSSCSmethodEditFieldLabel = uilabel(app.Panel);
            app.NRCSSCSmethodEditFieldLabel.Tooltip = {'The American Soil Conservation Service developed the formula, which was developed from data on a homogeneous agricultural area up to a size of 8 square kilometers.'};
            app.NRCSSCSmethodEditFieldLabel.Text = 'NRCS-SCS method ';
            app.NRCSSCSmethodEditField = uieditfield(app.Panel, 'numeric');
            app.NRCSSCSmethodEditField.Visible = 'off';
            app.NRCSSCSmethodEditField.Tooltip = {'The American Soil Conservation Service developed the formula, which was developed from data on a homogeneous agricultural area up to a size of 8 square kilometers.'; ''; 'Paramters needed: L = Length of main watercourse channel [m]; '; 'S_AVG = average main watercourse slope [mm^(-1)],'; 'CN = runoff curve number'};
            app.TexasDepartmentoftransportationsformulaLabel = uilabel(app.Panel);
            app.TexasDepartmentoftransportationsformulaLabel.Tooltip = {'This formula is a modification of the FAA''s equation; the Texas Transportation Service researched the concentration times to apply it in the rational method to design drainage systems on roads in Texas; the upper limit for this formula is an area of ​​0.80 square kilometers.'};
            app.TexasDepartmentoftransportationsformulaLabel.Text = {'Texas Department of '; 'transportation’s formula'};
            app.TexasDepartmentoftransportationsformulaEditField = uieditfield(app.Panel, 'numeric');
            app.TexasDepartmentoftransportationsformulaEditField.Visible = 'off';
            app.TexasDepartmentoftransportationsformulaEditField.Tooltip = {'This formula is a modification of the FAA''s equation; the Texas Transportation Service researched the concentration times to apply it in the rational method to design drainage systems on roads in Texas; the upper limit for this formula is an area of ​​0.80 square kilometers.'; 'Paramters needed: L = Length of main watercourse channel [m]; '; 'S = average main watercourse slope [mm^(-1)],'; 'C = runoff coefficient'};
            app.VenturasFormulaEditFieldLabel = uilabel(app.Panel);
            app.VenturasFormulaEditFieldLabel.Tooltip = {'The formula is derived for the analysis of precipitation and stream flow, in some rural basin areas in eastern Italy (Po Valley), the recommended upper limit for the area in this formula is 10 square kilometers.'};
            app.VenturasFormulaEditFieldLabel.Text = 'Ventura’s Formula';
            app.VenturasFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.VenturasFormulaEditField.Visible = 'off';
            app.VenturasFormulaEditField.Tooltip = {'The formula is derived for the analysis of precipitation and stream flow, in some rural basin areas in eastern Italy (Po Valley), the recommended upper limit for the area in this formula is 10 square kilometers.'; 'Paramters needed:  A= cathment area in [km^2 ];'; 'S = average main watercourse slope [mm^(-1)]'};
            app.PasinisFormulaEditFieldLabel = uilabel(app.Panel);
            app.PasinisFormulaEditFieldLabel.Tooltip = {'The formula was developed for large drainage basins in the Po region, for 40-70,000 square kilometers of basins.'};
            app.PasinisFormulaEditFieldLabel.Text = 'Pasini’s Formula';
            app.PasinisFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.PasinisFormulaEditField.Visible = 'off';
            app.PasinisFormulaEditField.Tooltip = {'The formula was developed for large drainage basins in the Po region, for 40-70,000 square kilometers of basins.'; 'Paramters needed: L = Length of main watercourse channel [m]; A= cathment area in [km^2 ];'; 'S = average main watercourse slope [mm^(-1)]'};
            app.TournonsFormulaEditFieldLabel = uilabel(app.Panel);
            app.TournonsFormulaEditFieldLabel.Tooltip = {'This formula was developed for nine mountain drainage basins, in Piedmont in the upper part of the Po basin in Italy and is suitable for an area of ​​30-170 square kilometers.'};
            app.TournonsFormulaEditFieldLabel.Text = 'Tournon’s Formula';
            app.TournonsFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.TournonsFormulaEditField.Visible = 'off';
            app.TournonsFormulaEditField.Tooltip = {'This formula was developed for nine mountain drainage basins, in Piedmont in the upper part of the Po basin in Italy and is suitable for an area of ​​30-170 square kilometers.'; 'Paramters needed: L = Length of main watercourse channel [m]; A= cathment area in [km^2 ];'; 'S = average main watercourse slope [mm^(-1)], Slope_AVG'};
            app.PuglisiandZanframundosFormulaEditFieldLabel = uilabel(app.Panel);
            app.PuglisiandZanframundosFormulaEditFieldLabel.Tooltip = {'The formula developers analyzed six basins in Italy and produced the formula, which is ideal for drainage basins in a 43-94 square kilometers size range.'};
            app.PuglisiandZanframundosFormulaEditFieldLabel.Text = 'Puglisi and Zanframundo’s Formula';
            app.PuglisiandZanframundosFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.PuglisiandZanframundosFormulaEditField.Visible = 'off';
            app.PuglisiandZanframundosFormulaEditField.Tooltip = {'The formula developers analyzed six basins in Italy and produced the formula, which is ideal for drainage basins in a 43-94 square kilometers size range.'; 'Paramters needed: L = Length of main watercourse channel [m]; '; 'Delta_H = is the diffrence between the maximum elevation of the whole catchment and theoutlet section'};
            app.FattorelliandMarchisFormulaEditFieldLabel = uilabel(app.Panel);
            app.FattorelliandMarchisFormulaEditFieldLabel.Tooltip = {'This formula is derived from data collected in 6 drainage basins in the thousand regions, in a range of areas 200-7 square kilometers in size.'};
            app.FattorelliandMarchisFormulaEditFieldLabel.Text = 'Fattorelli and Marchi’s Formula';
            app.FattorelliandMarchisFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.FattorelliandMarchisFormulaEditField.Visible = 'off';
            app.FattorelliandMarchisFormulaEditField.Tooltip = {'This formula is derived from data collected in 6 drainage basins in the thousand regions, in a range of areas 200-7 square kilometers in size.'; 'Paramters needed: L = Length of main watercourse channel [m]; '; 'Delta_H = is the diffrence between the maximum elevation of the whole catchment and theoutlet section'};
            app.PezzolisFormulaEditFieldLabel = uilabel(app.Panel);
            app.PezzolisFormulaEditFieldLabel.Tooltip = {'This formula was developed for several small areas, mountain "watersheds" in Italy.'};
            app.PezzolisFormulaEditFieldLabel.Text = 'Pezzoli’s Formula';
            app.PezzolisFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.PezzolisFormulaEditField.Visible = 'off';
            app.PezzolisFormulaEditField.Tooltip = {'This formula was developed for several small areas, mountain "watersheds" in Italy.'; 'Paramters needed: L = Length of main watercourse channel [m]; '; 'S = average main watercourse slope [mm^(-1)]'};
            app.EatonsMethodEditFieldLabel = uilabel(app.Panel);
            app.EatonsMethodEditFieldLabel.Tooltip = {'The equation was developed for seven rivers in Australia, with a drainage basin of 834-124 square kilometers.'};
            app.EatonsMethodEditFieldLabel.Text = 'Eaton’s Method';
            app.EatonsMethodEditField = uieditfield(app.Panel, 'numeric');
            app.EatonsMethodEditField.Visible = 'off';
            app.EatonsMethodEditField.Tooltip = {'The equation was developed for seven rivers in Australia, with a drainage basin of 834-124 square kilometers.'; ''; 'Paramters needed: L = Length of main watercourse channel [m]; '; 'S = average main watercourse slope [mm^(-1)]; A= cathment area in [km^2 ]; r = is the branching factor varies from 1 to 2'};
            app.SheridansFormulaEditFieldLabel = uilabel(app.Panel);
            app.SheridansFormulaEditFieldLabel.Tooltip = {'This formula is derived from 9 watershed areas in Georgia and Florida, with drainage basins ranging from 2.62 to 334.24 square kilometers. With a track length ranging from 2.45 to 41.13 kilometers and a channel slope ranging from 0.001 to 0.0037.'};
            app.SheridansFormulaEditFieldLabel.Text = 'Sheridan’s Formula';
            app.SheridansFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.SheridansFormulaEditField.Visible = 'off';
            app.SheridansFormulaEditField.Tooltip = {'This formula is derived from 9 watershed areas in Georgia and Florida, with drainage basins ranging from 2.62 to 334.24 square kilometers. With a track length ranging from 2.45 to 41.13 kilometers and a channel slope ranging from 0.001 to 0.0037.'; 'Paramters needed: L = Length of main watercourse channel [m]; S = average main watercourse slope [mm^(-1)]'};
            app.TmezsFormulaEditFieldLabel = uilabel(app.Panel);
            app.TmezsFormulaEditFieldLabel.Tooltip = {'The formula is a derivative of data collected in several drainage basins in Spain whose area is less than 3,000 square kilometers.'};
            app.TmezsFormulaEditFieldLabel.Text = 'Témez’s Formula';
            app.TmezsFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.TmezsFormulaEditField.Visible = 'off';
            app.TmezsFormulaEditField.Tooltip = {'The formula is a derivative of data collected in several drainage basins in Spain whose area is less than 3,000 square kilometers.'; 'Paramters needed: L = Length of main watercourse channel [m]; S = average main watercourse slope [mm^(-1)]'};
            app.ChowsModelEditFieldLabel = uilabel(app.Panel);
            app.ChowsModelEditFieldLabel.Tooltip = {'The equation was developed from 20 drainage basins in a rural area in the USA, whose drainage basin area ranges from 0.01 to 18.5 square kilometers. The central slope of the river ranges from 0.0051 to 0.09.'};
            app.ChowsModelEditFieldLabel.Text = 'Chow’s Model';
            app.ChowsModelEditField = uieditfield(app.Panel, 'numeric');
            app.ChowsModelEditField.Visible = 'off';
            app.ChowsModelEditField.Tooltip = {'The equation was developed from 20 drainage basins in a rural area in the USA, whose drainage basin area ranges from 0.01 to 18.5 square kilometers. The central slope of the river ranges from 0.0051 to 0.09.'; 'Paramters needed: L = Length of main watercourse channel [m]; S = average main watercourse slope [mm^(-1)]'};
            app.DoogesFormulaEditFieldLabel = uilabel(app.Panel);
            app.DoogesFormulaEditFieldLabel.Tooltip = {'This equation was developed from 10 rural catchment areas in Ireland with drainage basin areas ranging from 145 to 948 square kilometers. The data was collected and presented in O''Kelly''s work.'};
            app.DoogesFormulaEditFieldLabel.Text = 'Dooge’s Formula';
            app.DoogesFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.DoogesFormulaEditField.Visible = 'off';
            app.DoogesFormulaEditField.Tooltip = {'This equation was developed from 10 rural catchment areas in Ireland with drainage basin areas ranging from 145 to 948 square kilometers. The data was collected and presented in O''Kelly''s work.'; 'Paramters needed:; S = average main watercourse slope [mm^(-1)]; A= cathment area in [km^2 ];'};
            app.HatkanirandSezenEditFieldLabel = uilabel(app.Panel);
            app.HatkanirandSezenEditFieldLabel.Tooltip = {'This is the result of a statistical analysis from a series of data taken from 10 drainage basins in Turkey, characterized by an area size between 10.6 and 9,867 square kilometers and a central river course length ranging from 5 to 231 kilometers.'};
            app.HatkanirandSezenEditFieldLabel.Text = 'Hatkanir and Sezen';
            app.HatkanirandSezenEditField = uieditfield(app.Panel, 'numeric');
            app.HatkanirandSezenEditField.Visible = 'off';
            app.HatkanirandSezenEditField.Tooltip = {'This is the result of a statistical analysis from a series of data taken from 10 drainage basins in Turkey, characterized by an area size between 10.6 and 9,867 square kilometers and a central river course length ranging from 5 to 231 kilometers.'; 'Paramters needed: L = Length of main watercourse channel [m]'};
            app.EspeysMethodEditFieldLabel = uilabel(app.Panel);
            app.EspeysMethodEditFieldLabel.Tooltip = {'This formula is derived from 11 rural "watershed" areas in Texas, Oklahoma, and New Mexico. Six areas were larger than 8.5 square kilometers, one had an area of ​​3.3 square kilometers, three were in the range of 0.36-0.40 square kilometers, and the smallest was 0.09 square kilometers.'};
            app.EspeysMethodEditFieldLabel.Text = 'Espey’s Method';
            app.EspeysMethodEditField = uieditfield(app.Panel, 'numeric');
            app.EspeysMethodEditField.Visible = 'off';
            app.EspeysMethodEditField.Tooltip = {'This formula is derived from 11 rural "watershed" areas in Texas, Oklahoma, and New Mexico. Six areas were larger than 8.5 square kilometers, one had an area of ​​3.3 square kilometers, three were in the range of 0.36-0.40 square kilometers, and the smallest was 0.09 square kilometers.'; 'Paramters needed: L = Length of main watercourse channel [m]; S = average main watercourse slope [mm^(-1)]'};
            app.PilgrimandMcDermottsFormulaLabel = uilabel(app.Panel);
            app.PilgrimandMcDermottsFormulaLabel.Tooltip = {'The formula is derived from 96 drainage basins from South Wales in Australia.'};
            app.PilgrimandMcDermottsFormulaLabel.Text = {'Pilgrim and McDermott’s'; ' Formula'};
            app.PilgrimandMcDermottsFormulaEditField = uieditfield(app.Panel, 'numeric');
            app.PilgrimandMcDermottsFormulaEditField.Visible = 'off';
            app.PilgrimandMcDermottsFormulaEditField.Tooltip = {'The formula is derived from 96 drainage basins from South Wales in Australia.'; 'Parameters needed: A= cathment area in [km^2 ];'};
            app.FerrosModelEditFieldLabel = uilabel(app.Panel);
            app.FerrosModelEditFieldLabel.Tooltip = {'Ferrero statistically analyzed data collected in Italy from a hydrological paper and did a regression process to create this equation.'};
            app.FerrosModelEditFieldLabel.Text = 'Ferro’s Model';
            app.FerrosModelEditField = uieditfield(app.Panel, 'numeric');
            app.FerrosModelEditField.Visible = 'off';
            app.FerrosModelEditField.Tooltip = {'Ferrero statistically analyzed data collected in Italy from a hydrological paper and did a regression process to create this equation.'; 'Parameters needed: A= cathment area in [km^2 ];'};
            app.BocchiolasModelEditFieldLabel = uilabel(app.Panel);
            app.BocchiolasModelEditFieldLabel.Tooltip = {'This formula is a correction of the SCS formula, which was made with the addition of observations from data collected in 38 watersheds in Italy ranging in size from 92 to 142 square kilometers.'};
            app.BocchiolasModelEditFieldLabel.Text = 'Bocchiola’s Model';
            app.BocchiolasModelEditField = uieditfield(app.Panel, 'numeric');
            app.BocchiolasModelEditField.Visible = 'off';
            app.BocchiolasModelEditField.Tooltip = {'This formula is a correction of the SCS formula, which was made with the addition of observations from data collected in 38 watersheds in Italy ranging in size from 92 to 142 square kilometers.'; 'Parameters needed: L = Length of main watercourse channel [m]; S = average main watercourse slope [mm^(-1)]; CN = runoff curve number'};
            app.TimeofConcentrationHoursLabel = uilabel(app.Panel);
            app.TimeofConcentrationHoursLabel.Text = 'Time of Concentration [Hours]';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Value = {'1.Hover your mouse on the formula you want to use for information on which paramters needed.'; ''; '2.Mark the check-box for the formulas you want to use, those formulas will count in "Average Tc".'; ''; '3. Add the parameters you have, or add the time manually or both for averaging.'; ''; '4.Click "Compute"'};
            app.AddmanuallyEditFieldLabel = uilabel(app.Panel);
            app.AddmanuallyEditFieldLabel.Tooltip = {'The American Soil Conservation Service developed the formula, which was developed from data on a homogeneous agricultural area up to a size of 8 square kilometers.'};
            app.AddmanuallyEditFieldLabel.Text = 'Add manually';
            app.AddmanuallyEditField = uieditfield(app.Panel, 'numeric');
            app.AddmanuallyEditField.Tooltip = {'The American Soil Conservation Service developed the formula, which was developed from data on a homogeneous agricultural area up to a size of 8 square kilometers.'};
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.Panel);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.Panel);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.Panel);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.Panel);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.Panel);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.Panel);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.Panel);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.Panel);
            app.CheckBox_10.Text = '';
            app.CheckBox_11 = uicheckbox(app.Panel);
            app.CheckBox_11.Text = '';
            app.CheckBox_12 = uicheckbox(app.Panel);
            app.CheckBox_12.Text = '';
            app.CheckBox_13 = uicheckbox(app.Panel);
            app.CheckBox_13.Text = '';
            app.CheckBox_14 = uicheckbox(app.Panel);
            app.CheckBox_14.Text = '';
            app.CheckBox_15 = uicheckbox(app.Panel);
            app.CheckBox_15.Text = '';
            app.CheckBox_16 = uicheckbox(app.Panel);
            app.CheckBox_16.Text = '';
            app.CheckBox_17 = uicheckbox(app.Panel);
            app.CheckBox_17.Text = '';
            app.CheckBox_18 = uicheckbox(app.Panel);
            app.CheckBox_18.Text = '';
            app.CheckBox_19 = uicheckbox(app.Panel);
            app.CheckBox_19.Text = '';
            app.CheckBox_20 = uicheckbox(app.Panel);
            app.CheckBox_20.Text = '';
            app.CheckBox_21 = uicheckbox(app.Panel);
            app.CheckBox_21.Text = '';
            app.CheckBox_22 = uicheckbox(app.Panel);
            app.CheckBox_22.Text = '';
            app.CheckBox_23 = uicheckbox(app.Panel);
            app.CheckBox_23.Text = '';
            app.CheckBox_24 = uicheckbox(app.Panel);
            app.CheckBox_24.Text = '';
            app.CheckBox_25 = uicheckbox(app.Panel);
            app.CheckBox_25.Text = '';
            app.ClearButton_3 = uibutton(app.Panel, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Text = 'Clear';
            app.AverageTcHoursEditField_2Label_2 = uilabel(app.Panel);
            app.AverageTcHoursEditField_2Label_2.Text = {'Average Tc '; '[Hours]'};
            app.AverageTcHoursEditField_2 = uieditfield(app.Panel, 'numeric');
            app.RainIntensityTab = uitab(app.TabGroup);
            app.RainIntensityTab.Title = '2.Rain Intensity';
            app.AdjustedCalculationPanel = uipanel(app.RainIntensityTab);
            app.AdjustedCalculationPanel.Title = 'Adjusted Calculation';
            app.CheckBox_26 = uicheckbox(app.AdjustedCalculationPanel);
            app.CheckBox_26.Text = '';
            app.CheckBox_27 = uicheckbox(app.AdjustedCalculationPanel);
            app.CheckBox_27.Text = '';
            app.CheckBox_28 = uicheckbox(app.AdjustedCalculationPanel);
            app.CheckBox_28.Text = '';
            app.StationsPanel = uipanel(app.RainIntensityTab);
            app.StationsPanel.Title = 'Stations';
            app.TheRainintensitymmhrindifferentdistributionsPanel = uipanel(app.RainIntensityTab);
            app.TheRainintensitymmhrindifferentdistributionsPanel.Title = 'The Rain intensity [mm/hr] in different distributions';
            app.AverageIntensityEditFieldLabel = uilabel(app.RainIntensityTab);
            app.AverageIntensityEditFieldLabel.Text = 'Average Intensity';
            app.AverageIntensityEditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.AverageIntensityEditField.ValueDisplayFormat = '%.1f';
            app.CalculateButton = uibutton(app.RainIntensityTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.YEditFieldLabel = uilabel(app.RainIntensityTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RainIntensityTab, 'text');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.ValueChangingFcn = createCallbackFcn(app, @YEditFieldValueChanging, true);
            app.YEditField.Tag = '2';
            app.XEditField_2Label = uilabel(app.RainIntensityTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField = uieditfield(app.RainIntensityTab, 'text');
            app.XEditField.Tag = '1';
            app.DurationDropDownLabel = uilabel(app.RainIntensityTab);
            app.DurationDropDownLabel.Text = 'Duration';
            app.DurationDropDown = uidropdown(app.RainIntensityTab);
            app.DurationDropDown.Items = {'10 min', '20 min', '30 min', '40 min', '50 min', '1 hr', '1.5 hr', '2 hr', '2.5 hr', '3 hr', '3.5 hr', '4 hr', '4.5 hr', '5 hr', '6 hr'};
            app.DurationDropDown.Value = '10 min';
            app.ReturnPeriodDropDownLabel = uilabel(app.RainIntensityTab);
            app.ReturnPeriodDropDownLabel.Text = 'Return Period';
            app.ReturnPeriodDropDown = uidropdown(app.RainIntensityTab);
            app.ReturnPeriodDropDown.Items = {'2 Years', '5 Years', '10 Years', '25 Years', '50 Years', '100 Years', '200 Years', '500 Years', '1000 Years'};
            app.ReturnPeriodDropDown.Value = '2 Years';
            app.mmhrLabel = uilabel(app.RainIntensityTab);
            app.mmhrLabel.Text = 'mm/hr';
            app.mmhrLabel_2 = uilabel(app.RainIntensityTab);
            app.mmhrLabel_2.Text = 'mm/hr';
            app.kmLabel = uilabel(app.RainIntensityTab);
            app.kmLabel.Text = 'km';
            app.kmLabel_2 = uilabel(app.RainIntensityTab);
            app.kmLabel_2.Text = 'km';
            app.kmLabel_3 = uilabel(app.RainIntensityTab);
            app.kmLabel_3.Text = 'km';
            app.gumb1EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.gumb1EditField.ValueDisplayFormat = '%.1f';
            app.gumb3EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.gumb3EditField.ValueDisplayFormat = '%.1f';
            app.gumb2EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.gumb2EditField.ValueDisplayFormat = '%.1f';
            app.logPearson1EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.logPearson1EditField.ValueDisplayFormat = '%.1f';
            app.logPearson3EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.logPearson3EditField.ValueDisplayFormat = '%.1f';
            app.logPearson2EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.logPearson2EditField.ValueDisplayFormat = '%.1f';
            app.logNormal1EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.logNormal1EditField.ValueDisplayFormat = '%.1f';
            app.logNormal2EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.logNormal2EditField.ValueDisplayFormat = '%.1f';
            app.logNormal3EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.logNormal3EditField.ValueDisplayFormat = '%.1f';
            app.station1EditFieldLabel = uilabel(app.RainIntensityTab);
            app.station1EditFieldLabel.Text = 'station 1';
            app.station1EditField = uieditfield(app.RainIntensityTab, 'text');
            app.station2EditFieldLabel = uilabel(app.RainIntensityTab);
            app.station2EditFieldLabel.Text = 'station 2';
            app.station2EditField = uieditfield(app.RainIntensityTab, 'text');
            app.station3EditFieldLabel = uilabel(app.RainIntensityTab);
            app.station3EditFieldLabel.Text = 'station 3';
            app.station3EditField = uieditfield(app.RainIntensityTab, 'text');
            app.DIS1EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.DIS2EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.DIS3EditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.GumbleCheckBox = uicheckbox(app.RainIntensityTab);
            app.GumbleCheckBox.ValueChangedFcn = createCallbackFcn(app, @GumbleCheckBoxValueChanged, true);
            app.GumbleCheckBox.Text = 'Gumble';
            app.LogPearsontypeIIICheckBox = uicheckbox(app.RainIntensityTab);
            app.LogPearsontypeIIICheckBox.ValueChangedFcn = createCallbackFcn(app, @LogPearsontypeIIICheckBoxValueChanged, true);
            app.LogPearsontypeIIICheckBox.Text = {'Log Pearson'; '      type III'};
            app.LogNormalCheckBox = uicheckbox(app.RainIntensityTab);
            app.LogNormalCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogNormalCheckBoxValueChanged, true);
            app.LogNormalCheckBox.Text = 'Log-Normal';
            app.mmhrLabel_3 = uilabel(app.RainIntensityTab);
            app.mmhrLabel_3.Text = 'mm/hr';
            app.LinearInterpolationEditFieldLabel = uilabel(app.RainIntensityTab);
            app.LinearInterpolationEditFieldLabel.Text = 'Linear Interpolation';
            app.LinearInterpolationEditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.LinearInterpolationEditField.ValueDisplayFormat = '%.1f';
            app.Image = uiimage(app.RainIntensityTab);
            app.Image.Tooltip = {'Click to open GovMap'};
            app.Image.URL = 'https://www.govmap.gov.il/?c=204000,595000&z=0';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Israel_station_map_we_used_in_the_project2.png');
            app.PlottingPostionPanel = uipanel(app.RainIntensityTab);
            app.PlottingPostionPanel.Title = 'Plotting Postion';
            app.plotting_postion1EditField = uieditfield(app.PlottingPostionPanel, 'numeric');
            app.plotting_postion1EditField.ValueDisplayFormat = '%.1f';
            app.plotting_postion2EditField = uieditfield(app.PlottingPostionPanel, 'numeric');
            app.plotting_postion2EditField.ValueDisplayFormat = '%.1f';
            app.plotting_postion3EditField = uieditfield(app.PlottingPostionPanel, 'numeric');
            app.plotting_postion3EditField.ValueDisplayFormat = '%.1f';
            app.plottingpositionCheckBox = uicheckbox(app.PlottingPostionPanel);
            app.plottingpositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @plottingpositionCheckBoxValueChanged, true);
            app.plottingpositionCheckBox.Text = {'plotting'; 'postion'};
            app.Hyperlink = uihyperlink(app.RainIntensityTab);
            app.Hyperlink.URL = 'https://www.govmap.gov.il/?c=204000,595000&z=0';
            app.Hyperlink.Text = 'Gov Map';
            app.ClearButton = uibutton(app.RainIntensityTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FinalIntensitymmhrEditFieldLabel = uilabel(app.RainIntensityTab);
            app.FinalIntensitymmhrEditFieldLabel.Tooltip = {'Average of the marked Adjusted Calculations'};
            app.FinalIntensitymmhrEditFieldLabel.Text = 'Final Intensity [mm/hr]';
            app.FinalIntensitymmhrEditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.FinalIntensitymmhrEditField.RoundFractionalValues = 'on';
            app.FinalIntensitymmhrEditField.ValueDisplayFormat = '%.0f';
            app.FinalIntensitymmhrEditField.Tooltip = {'Average of the marked Adjusted Calculations'};
            app.AverageIntensityIDWEditFieldLabel = uilabel(app.RainIntensityTab);
            app.AverageIntensityIDWEditFieldLabel.Text = {'Average Intensity'; '[IDW]'};
            app.AverageIntensityIDWEditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.AverageIntensityIDWEditField.ValueDisplayFormat = '%.1f';
            app.AverageTcHoursEditField_2Label = uilabel(app.RainIntensityTab);
            app.AverageTcHoursEditField_2Label.Text = {'Average Tc '; '[Hours]'};
            app.AverageTcHoursEditField = uieditfield(app.RainIntensityTab, 'numeric');
            app.TextArea_2 = uitextarea(app.RainIntensityTab);
            app.TextArea_2.Value = {'1.Mark the distributions you want to use in the calculations in the checkbox*'; ''; '2.Mark the checkbox for the method you want to implement on the distribution data inside the "Adjusted Calculation" box.'; ''; '3.Insert a Coordinate (x,y), Return period and Duration'; ''; '4.Click "Calculate"'; ''; '*Plotting postion is not included in the "Adjusted Calculations" on the other distributions the methods can be implement'};
            app.CoordinatesLabel = uilabel(app.RainIntensityTab);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.IDFAverageTable = uitab(app.TabGroup);
            app.IDFAverageTable.Title = '3.IDF Average Table';
            app.UITable = uitable(app.IDFAverageTable);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.IDFGraphTab = uitab(app.TabGroup);
            app.IDFGraphTab.Title = '4.IDF Graph';
            app.UIAxes = uiaxes(app.IDFGraphTab);
            title(app.UIAxes, 'Average IDF')
            xlabel(app.UIAxes, 'Duration [Hours]')
            ylabel(app.UIAxes, 'Rain Intensity [mm\hr]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RunoffTab = uitab(app.TabGroup);
            app.RunoffTab.Title = '5.Runoff';
            app.UIAxes2 = uiaxes(app.RunoffTab);
            xlabel(app.UIAxes2, 'Return Period [Years]')
            ylabel(app.UIAxes2, 'Rain Intensity [mm\hr]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [1 100];
            app.UIAxes2.XLimitMethod = 'padded';
            app.UIAxes2.XTick = [2 3 4 5 10 100];
            app.UIAxes2.XScale = 'log';
            app.Qpm3hrEditFieldLabel = uilabel(app.RunoffTab);
            app.Qpm3hrEditFieldLabel.Text = 'Qp [m^3/hr]';
            app.Qpm3hrEditField = uieditfield(app.RunoffTab, 'numeric');
            app.ComputeButton_2 = uibutton(app.RunoffTab, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_2Pushed, true);
            app.ComputeButton_2.Text = 'Compute';
            app.ClearButton_2 = uibutton(app.RunoffTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.AdunamLabel = uilabel(app.RunoffTab);
            app.AdunamLabel.Text = 'A [dunam]';
            app.AdunamEditField_2 = uieditfield(app.RunoffTab, 'text');
            app.CEditField_2Label = uilabel(app.RunoffTab);
            app.CEditField_2Label.Text = 'C';
            app.CEditField_2 = uieditfield(app.RunoffTab, 'text');
            app.ExportDatatoxlsxButton = uibutton(app.RunoffTab, 'push');
            app.ExportDatatoxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoxlsxButtonPushed, true);
            app.ExportDatatoxlsxButton.Text = 'Export Data to .xlsx';
            app.FinalIntensitymmhrEditField_2Label = uilabel(app.RunoffTab);
            app.FinalIntensitymmhrEditField_2Label.Tooltip = {'Average of the marked Adjusted Calculations'};
            app.FinalIntensitymmhrEditField_2Label.Text = 'Final Intensity [mm/hr]';
            app.FinalIntensitymmhrEditField_2 = uieditfield(app.RunoffTab, 'numeric');
            app.FinalIntensitymmhrEditField_2.RoundFractionalValues = 'on';
            app.FinalIntensitymmhrEditField_2.Tooltip = {'Average of the marked Adjusted Calculations'};
            app.PlottingpostiongraphisbaseontheneareststationLabel = uilabel(app.RunoffTab);
            app.PlottingpostiongraphisbaseontheneareststationLabel.Text = '*Plotting postion graph is base on the nearest station';
            app.ContextMenu = uicontextmenu(app.FluvalCalcUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.FluvalCalcUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Intencity_Calc_13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FluvalCalcUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FluvalCalcUIFigure)
        end
    end
end